classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        PushTool2                       matlab.ui.container.toolbar.PushTool
        PushTool3                       matlab.ui.container.toolbar.PushTool
        PushTool4                       matlab.ui.container.toolbar.PushTool
        GridLayout                      matlab.ui.container.GridLayout
        WindowTabGroup                  matlab.ui.container.TabGroup
        ImportingTab                    matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        ProceedafterSelectionButton     matlab.ui.control.Button
        SelectSTLsButton                matlab.ui.control.Button
        LoadDipoleCoilButton            matlab.ui.control.Button
        LoadPreviousSessionButton       matlab.ui.control.Button
        ImportStatusLamp                matlab.ui.control.Lamp
        UITable3                        matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        LoadNiftiDataButton             matlab.ui.control.Button
        LoadCoilButton                  matlab.ui.control.Button
        PlanningTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        LoadedCoilPositionsListBoxLabel  matlab.ui.control.Label
        DeleteCoilPositionButton        matlab.ui.control.Button
        AddCoilPositionButton           matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        GridLayout12                    matlab.ui.container.GridLayout
        GridLayout15                    matlab.ui.container.GridLayout
        ScalemmSlider                   matlab.ui.control.Slider
        ScalemmSliderLabel              matlab.ui.control.Label
        GridLayout14                    matlab.ui.container.GridLayout
        NiftiDataDropDown               matlab.ui.control.DropDown
        NiftiDataDropDownLabel          matlab.ui.control.Label
        HeadCompartmentsDropDown        matlab.ui.control.DropDown
        HeadCompartmentsDropDownLabel   matlab.ui.control.Label
        GridLayout13                    matlab.ui.container.GridLayout
        VectorfromcoilSwitch            matlab.ui.control.Switch
        VectorfromcoilSwitchLabel       matlab.ui.control.Label
        FieldVectorSwitch               matlab.ui.control.Switch
        FieldVectorLabel                matlab.ui.control.Label
        VisualControlsLabel             matlab.ui.control.Label
        AnimateButton                   matlab.ui.control.Button
        LoadedCoilPositionsListBox      matlab.ui.control.ListBox
        CoilTransformationMatrixPanel   matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        MatrixField44                   matlab.ui.control.NumericEditField
        MatrixField33                   matlab.ui.control.NumericEditField
        MatrixField34                   matlab.ui.control.NumericEditField
        MatrixField24                   matlab.ui.control.NumericEditField
        MatrixField43                   matlab.ui.control.NumericEditField
        MatrixField23                   matlab.ui.control.NumericEditField
        MatrixField14                   matlab.ui.control.NumericEditField
        MatrixField12                   matlab.ui.control.NumericEditField
        MatrixField22                   matlab.ui.control.NumericEditField
        MatrixField32                   matlab.ui.control.NumericEditField
        MatrixField13                   matlab.ui.control.NumericEditField
        MatrixField11                   matlab.ui.control.NumericEditField
        MatrixField42                   matlab.ui.control.NumericEditField
        MatrixField41                   matlab.ui.control.NumericEditField
        MatrixField31                   matlab.ui.control.NumericEditField
        MatrixField21                   matlab.ui.control.NumericEditField
        TransformationMatrixSwitch      matlab.ui.control.Switch
        TransformationMatrixVisabilityLabel  matlab.ui.control.Label
        ContributorsAltonMilesGrahamOrnsteinKseniiaRomanovaPanel  matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        Versionv051alphaLabel           matlab.ui.control.Label
        GridLayout8                     matlab.ui.container.GridLayout
        CoilControlsTabGroup            matlab.ui.container.TabGroup
        AutomaticTab                    matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        GridLayout22                    matlab.ui.container.GridLayout
        GridLayout51                    matlab.ui.container.GridLayout
        AddObservationLineButton        matlab.ui.control.Button
        AddObservationPlanesButton      matlab.ui.control.Button
        GridLayout26                    matlab.ui.container.GridLayout
        ResetRotationButton_3           matlab.ui.control.Button
        ResetTranslationButton_3        matlab.ui.control.Button
        GridLayout23                    matlab.ui.container.GridLayout
        CoilAxisRotationEditField       matlab.ui.control.NumericEditField
        CoilAxisRotationEditFieldLabel  matlab.ui.control.Label
        GridLayout17                    matlab.ui.container.GridLayout
        GridLayout21                    matlab.ui.container.GridLayout
        UpdateDisplayButton             matlab.ui.control.Button
        GridLayout20                    matlab.ui.container.GridLayout
        CoilSkinDistanceEditField       matlab.ui.control.NumericEditField
        DistanceFromSkinLabel           matlab.ui.control.Label
        GridLayout19                    matlab.ui.container.GridLayout
        RemovePointsButton              matlab.ui.control.Button
        UpdateTargetButton              matlab.ui.control.Button
        GridLayout18                    matlab.ui.container.GridLayout
        PointZValEditField              matlab.ui.control.NumericEditField
        PointYValEditField              matlab.ui.control.NumericEditField
        PointXValEditField              matlab.ui.control.NumericEditField
        ChosenTargetLabel               matlab.ui.control.Label
        ManualTab                       matlab.ui.container.Tab
        GridLayout27                    matlab.ui.container.GridLayout
        GridLayout31                    matlab.ui.container.GridLayout
        ZAxisRotationEditField          matlab.ui.control.NumericEditField
        YAxisRotationpiradsLabel_4      matlab.ui.control.Label
        YAxisRotationEditField          matlab.ui.control.NumericEditField
        YAxisRotationpiradsLabel_3      matlab.ui.control.Label
        XAxisRotationEditField          matlab.ui.control.NumericEditField
        XAxisRotationLabel              matlab.ui.control.Label
        GridLayout30                    matlab.ui.container.GridLayout
        ZTranslationEditField           matlab.ui.control.NumericEditField
        ZTranslationLabel               matlab.ui.control.Label
        YTranslationEditField           matlab.ui.control.NumericEditField
        YTranslationLabel               matlab.ui.control.Label
        XTranslationEditField           matlab.ui.control.NumericEditField
        XTranslationLabel               matlab.ui.control.Label
        GridLayout29                    matlab.ui.container.GridLayout
        ResetRotationButton_4           matlab.ui.control.Button
        GridLayout28                    matlab.ui.container.GridLayout
        ResetTranslationButton_4        matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PlanesTab                       matlab.ui.container.Tab
        GridLayout32                    matlab.ui.container.GridLayout
        GridLayout34                    matlab.ui.container.GridLayout
        GridLayout36                    matlab.ui.container.GridLayout
        GridLayout37                    matlab.ui.container.GridLayout
        CenterOnPointButton             matlab.ui.control.Button
        CoilDisplayVisibilitySwitch     matlab.ui.control.Switch
        CoilDisplayVisibilitySwitchLabel  matlab.ui.control.Label
        GridLayout38                    matlab.ui.container.GridLayout
        GridLayout41                    matlab.ui.container.GridLayout
        DeletePlaneButton               matlab.ui.control.Button
        GridLayout40                    matlab.ui.container.GridLayout
        AddNewPlaneButton               matlab.ui.control.Button
        GridLayout39                    matlab.ui.container.GridLayout
        PlaneWidthEditField             matlab.ui.control.NumericEditField
        PlaneWidthmmLabel               matlab.ui.control.Label
        GridLayout35                    matlab.ui.container.GridLayout
        XCoordEditField                 matlab.ui.control.NumericEditField
        XCoordLabel                     matlab.ui.control.Label
        ZCoordEditField                 matlab.ui.control.NumericEditField
        ZCoordLabel                     matlab.ui.control.Label
        YCoordEditField                 matlab.ui.control.NumericEditField
        YCoordLabel                     matlab.ui.control.Label
        GridLayout33                    matlab.ui.container.GridLayout
        DirectionDropDown               matlab.ui.control.DropDown
        DirectionDropDownLabel          matlab.ui.control.Label
        PlaneNameEditField              matlab.ui.control.EditField
        PlaneNameEditFieldLabel         matlab.ui.control.Label
        PlanesDropDown                  matlab.ui.control.DropDown
        PlanesDropDownLabel             matlab.ui.control.Label
        LinesTab                        matlab.ui.container.Tab
        GridLayout42                    matlab.ui.container.GridLayout
        GridLayout44                    matlab.ui.container.GridLayout
        GridLayout47                    matlab.ui.container.GridLayout
        GridLayout49                    matlab.ui.container.GridLayout
        CenterLineOnPointButton         matlab.ui.control.Button
        CoilDisplayLineVisibilitySwitch  matlab.ui.control.Switch
        CoilDisplayVisibilitySwitch_2Label  matlab.ui.control.Label
        GridLayout48                    matlab.ui.container.GridLayout
        DeleteLineButton                matlab.ui.control.Button
        AddNewLineButton                matlab.ui.control.Button
        GridLayout46                    matlab.ui.container.GridLayout
        ZVectorDirectionEditField       matlab.ui.control.NumericEditField
        ZVectorDirectionEditFieldLabel  matlab.ui.control.Label
        YVectorDirectionEditField       matlab.ui.control.NumericEditField
        YVectorDirectionEditFieldLabel  matlab.ui.control.Label
        XVectorDirectionEditField       matlab.ui.control.NumericEditField
        XVectorDirectionEditFieldLabel  matlab.ui.control.Label
        GridLayout45                    matlab.ui.container.GridLayout
        ZCoordLineEditField             matlab.ui.control.NumericEditField
        ZCoordmmLabel                   matlab.ui.control.Label
        YCoordLineEditField             matlab.ui.control.NumericEditField
        YCoordmmLabel                   matlab.ui.control.Label
        XCoordLineEditField             matlab.ui.control.NumericEditField
        XCoordmmLabel                   matlab.ui.control.Label
        GridLayout43                    matlab.ui.container.GridLayout
        LineLengthEditField             matlab.ui.control.NumericEditField
        LineLengthLabel                 matlab.ui.control.Label
        LineNameEditField               matlab.ui.control.EditField
        LineNameEditFieldLabel          matlab.ui.control.Label
        LinesDropDown                   matlab.ui.control.DropDown
        LinesDropDownLabel              matlab.ui.control.Label
        CrossSectionDisplay             matlab.ui.control.UIAxes
        CoilDisplay                     matlab.ui.control.UIAxes
        ProcessingTab                   matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        TextArea_2                      matlab.ui.control.TextArea
        NumberOfThreadsForProcessingEditField  matlab.ui.control.NumericEditField
        NumberofcoresforprocessingLabel_9  matlab.ui.control.Label
        MaximumIterationsEditField      matlab.ui.control.NumericEditField
        MaximumIterationsEditFieldLabel  matlab.ui.control.Label
        AdvancedOptionsPanel            matlab.ui.container.Panel
        GridLayout52                    matlab.ui.container.GridLayout
        CoildIdtEditField               matlab.ui.control.NumericEditField
        CoildIdtEditFieldLabel          matlab.ui.control.Label
        CoilCurrentEditField            matlab.ui.control.NumericEditField
        CoilCurrentEditFieldLabel       matlab.ui.control.Label
        MinimumResidualEditField        matlab.ui.control.NumericEditField
        MinimumResidualEditFieldLabel   matlab.ui.control.Label
        AdvancedViewCheckBox            matlab.ui.control.CheckBox
        LampStartProcessing             matlab.ui.control.Lamp
        GeneralOptionsLabel             matlab.ui.control.Label
        StartProcessingButton           matlab.ui.control.Button
        PostProcessingVolumeTab         matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        CoilPPDisplay                   matlab.ui.control.UIAxes
        CrossSectionPPDisplay           matlab.ui.control.UIAxes
        GridLayout5                     matlab.ui.container.GridLayout
        VolumeCoilSelectionDropDown     matlab.ui.control.DropDown
        CoilSelectionLabel              matlab.ui.control.Label
        EFieldPlotLevels                matlab.ui.control.NumericEditField
        NumberofcoresforprocessingLabel_15  matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        EFieldThresholdHigh             matlab.ui.control.NumericEditField
        NumberofcoresforprocessingLabel_14  matlab.ui.control.Label
        EFieldThresholdLow              matlab.ui.control.NumericEditField
        NumberofcoresforprocessingLabel_13  matlab.ui.control.Label
        PlaneSelectionDropDown          matlab.ui.control.DropDown
        PlaneSelectionLabel             matlab.ui.control.Label
        RedrawPlotButton                matlab.ui.control.Button
        SolverDisplay                   matlab.ui.control.UIAxes
        PostProcessingSurfaceTab        matlab.ui.container.Tab
        GridLayout50                    matlab.ui.container.GridLayout
        SurfaceCoilSelectionDropDown    matlab.ui.control.DropDown
        CoilSelectionLabel_2            matlab.ui.control.Label
        RedrawSurfacePlotButton         matlab.ui.control.Button
        SurfaceThresholdHigh            matlab.ui.control.NumericEditField
        NumberofcoresforprocessingLabel_17  matlab.ui.control.Label
        SurfaceThresholdLow             matlab.ui.control.NumericEditField
        NumberofcoresforprocessingLabel_16  matlab.ui.control.Label
        TextArea_4                      matlab.ui.control.TextArea
        FieldValueTypeDropDown          matlab.ui.control.DropDown
        FieldSourceTypeDropDown         matlab.ui.control.DropDown
        FieldTypeDropDownLabel          matlab.ui.control.Label
        SurfaceEFieldLocationSwitch     matlab.ui.control.Switch
        SurfaceEFieldLocationSwitchLabel  matlab.ui.control.Label
        HeadCompartmentsDropDown_2Label  matlab.ui.control.Label
        ScalemmSlider_2Label            matlab.ui.control.Label
        SurfaceHeadCompartmentsDropDown  matlab.ui.control.DropDown
        LampSurfacePlot                 matlab.ui.control.Lamp
        ScaleSurfaceSlider              matlab.ui.control.Slider
        SurfaceDisplay                  matlab.ui.control.UIAxes
        ExportingTab                    matlab.ui.container.Tab
        ExportButton                    matlab.ui.control.Button
        LampExporting                   matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        DataExportTree                  matlab.ui.container.CheckBoxTree
        HeadModelNode                   matlab.ui.container.TreeNode
        CoilNode                        matlab.ui.container.TreeNode
        EFieldNode                      matlab.ui.container.TreeNode
        SurfacesNode                    matlab.ui.container.TreeNode
        SurfacesPlottingExampleNode     matlab.ui.container.TreeNode
        PlanesNode                      matlab.ui.container.TreeNode
    end


    properties (Access = public)
        skinPoint % The chosen point on the skin
        directionNVector % Normal direction vector for coil placement
        userpointobj % the display object for the user's point

        %Import table
        meshList % Cell array that stores read stl files from the import table
        meshInternalNames % Cell array that stores internal names from the import table
        meshConductivities % Cell array that stores conductivities from the import table
        meshPath % Cell array that stores pathes from the import table
        meshEncTissues % Cell array that stores enclosing tissues from the import table
        meshFileNames % Cell array that stores file names from the import table

        mesh    % CombinedMesh
        strcoil % coil's computational info
        coilCAD % coil's display info
        coil % ...
        nifti_filepath % nifti file names
        niftidata % data for the crosssectiondisplay
        niftidatapp % data for the crosssectionppdisplay

        model % the model struct for the brain containing:
        % P: the points for the brain mesh in mm
        % t: the triangles for the brain mesh
        % normals: the normal's of the brain's triangles
        % Center: the centers of the brain's triangles
        % Area: the areas of the brain's triangles
        % Indicator: the indicators for different layers
        % tissue: the names for different layers

        matrices % cell array of coil transformation matrices (1xn) with each index containing a 4x4 matrix for the coil position
        solvedmatrices % a copy of app.matrices made when running the solver
        positions % stores list containing item names for the coil positions
        isDipoleCoil % is true when using a dipole coil and false otherwise

        CoilDisplayObjects % a struct containing the display objects for the coil display with elements
        % coilpatch: the patch object of the coil
        % brainpatch: the patch object of the brain
        % light: the light object
        % coilNormalLine: the line object of the coil's normal line
        % coilFieldLine: the line object of the coil's field line
        % planes: a 1xn cell array consisting of the display objects for
        % the different planes. The object in index k will correspond to
        % the plane in app.planes{k}
        % lines: a 1xn cell array consisting of the display objects for
        % the different lines. The object in index k will correspond to
        % the plane in app.lines{k}

        CoilPPDisplayObjects % a struct containing the display objects for the coil post processing display with elements
        % coilpatch: the patch object of the coil
        % brainpatch: the patch object of the brain
        % light: the light object
        % coilNormalLine: the line object of the coil's normal line
        % coilFieldLine: the line object of the coil's field line
        % planes: a 1xn cell array consisting of the display objects for
        % the different planes. The object in index k will correspond to
        % the plane in app.planes{k}
        % lines: a 1xn cell array consisting of the display objects for
        % the different lines. The object in index k will correspond to
        % the plane in app.lines{k}

        planes % a 1xn cell array of different planes. Each idx contains a struct for a plane with elements:
        % name: the name of the plane
        % direction: the planes orientation ('xy', 'xz', or 'yz')
        % position: the planes center position in the form [xCoord, yCoord, zCoord] in m
        % width: the plane's width in m
        % visibility: boolean saying whether the plane should be visable on the 3d plot
        selectedplaneidx % the index of the currently selected plane in the planes cell array

        solvedplanes % the planes that were contained within app.planes during the last running of the solver
        EFieldObs2 % (nxp) cell array where n is the number of coil positions and p is the number of planes
        % Each index is a struct with components:
        % 

        EFieldSolution % (1xn) cell array where n is the number of coil positions containing structs that have components:
        % EPri, the primary EField
        % ESec, the secondary EField
        % EDiscin, the discontinuous EField on the inside of the compartments
        % EDiscout, the discontinuous EField on the outside of the compartments


        lines % a 1xn cell array of different lines. Each idx contains a struct for a line with elements:
        % name: the name of the line
        % direction: the direction vector of the line [XComponent, YComponent, ZComponent]
        % length: the length of the line (m)
        % position: the location of the start of the line [xCoord(m), yCoord(m), zCoord(m)]
        % visibility: boolean saying whether the line should be visable on the 3d plot
        selectedlineidx  % the index of the currently selected line in the lines cell array

        niftidisplaydata % contains the display objects for all of the added objects on the crosssectiondisplay
        % Properties: userpoint, coilcenterline, centerlineintersection,
        % fieldplane

        niftidisplaydatapp % contains the display objects for all of the added objects on the crosssectionppdisplay
        % Properties: userpoint, coilcenterline, centerlineintersection,
        % fieldplane

        dcm_obj % Handle for the UIFigure

        processingPlaneidx % Currently selected plane index for processing tab
        vecnormObs2 % vector normalized Obs2 values for use in process e field plot functions
        EFieldModel % Model used in processEField and plotEField with the correct data structure (not the same as app.mesh)


        h  % For font resizing
        hFontSize  % for font resizing


        xmlA % stores current from xml file DON'T BELIEVE THIS IS CURRENTLY USED


        pause % pauses the animate button

        slash % the slash character for either unix or windows as appropriate
        dir % the central directory of the app
    end

    methods (Access = public)

        function updateImage(app)
            %%  The main function called to update the UIAxes (with the coil and brain)

            %   rounds the fields in the matrix
            roundmatrixfield(app);

            %   displays the brain to the coil display
            updateBrainToCoilDisplay(app);

            %   displays the coil to the coil display
            updateCoilToCoilDisplay(app)
            %   updates the coil's normal and 
            updateCoilToCrossSectionDisplay(app);

            %   displays the plane cross-sections
            updatePlanesForPlanningTab(app);
        end
    end

    methods (Access = private)

        function addpathes(app)
            %   Ensure that this step works for unix and windows

            % check for file to set the main directory path
            if (~isempty(which('GUI')))
                app.dir = fileparts(fileparts(which('GUI')));
            else
                throwErrorPopup(app, 'File \"GUI.mlapp\" not found. Please make sure this file exists on path', 'Setup Error');
                return
            end

            if(~isunix)
                app.slash = '\';
            else
                app.slash = '/';
            end

            %adding folder/subfolders to path
            addpath(genpath(app.dir));

        end

        function roundmatrixfield(app)

            %   Rounds all of the fields in the matrix
            app.MatrixField11.Value = round(app.MatrixField11.Value, 10);
            app.MatrixField12.Value = round(app.MatrixField12.Value, 10);
            app.MatrixField13.Value = round(app.MatrixField13.Value, 10);
            app.MatrixField21.Value = round(app.MatrixField21.Value, 10);
            app.MatrixField22.Value = round(app.MatrixField22.Value, 10);
            app.MatrixField23.Value = round(app.MatrixField23.Value, 10);
            app.MatrixField31.Value = round(app.MatrixField31.Value, 10);
            app.MatrixField32.Value = round(app.MatrixField32.Value, 10);
            app.MatrixField33.Value = round(app.MatrixField33.Value, 10);
            app.MatrixField41.Value = round(app.MatrixField41.Value, 10);
            app.MatrixField42.Value = round(app.MatrixField42.Value, 10);
            app.MatrixField43.Value = round(app.MatrixField43.Value, 10);

        end

        function postImportSetup(app)

            edgeDistance = 0.2; %  Prevents errors from indexing out of bounds

            % Sets options of head compartments for the planning tab and,
            % if possible, set the selection to grey matter
            app.HeadCompartmentsDropDown.Items = app.meshInternalNames;
            app.SurfaceHeadCompartmentsDropDown.Items = app.meshInternalNames;
            if (any(strcmp(app.meshInternalNames,'GM')))
                app.HeadCompartmentsDropDown.Value = 'GM';
                app.SurfaceHeadCompartmentsDropDown.Value = 'GM';
            end

            app.NiftiDataDropDown.Items = app.nifti_filepath;

                        %   Resets matrix
            event = 0; % Basically null equivilent
            % Generates the main matrix
            ResetRotationButtonPushed(app, event);
            ResetTranslationButtonPushed(app, event);


            %reset the Planes and CoilDisplay information
            if (isfield(app.CoilDisplayObjects, 'planes'))
                for i=1:length(app.CoilDisplayObjects.planes)
                    delete(app.CoilDisplayObjects.planes{i});
                end
            end
            app.planes = cell(0);
            updateplanedropdown(app);
            app.PlaneNameEditField.Value = "";
            cla(app.CrossSectionDisplay);
            app.XCoordEditField.Value = 0;
            app.YCoordEditField.Value = 0;
            app.ZCoordEditField.Value = 0;
            app.PlaneWidthEditField.Value = 0;
            app.CoilDisplayVisibilitySwitch.Value = false;


            % Generate model struct
            app.model = bemfmm_assembleModel_app(app);

            % Set the correct surface sub-compartments for the surface
            % option in the app.DataExportTree in the Exporting tab

            for n = 1:length(app.DataExportTree.Children) % Find the correct node
                if (strcmp(app.DataExportTree.Children(n).Text, 'Surfaces'))

                    for j = 1:length(app.DataExportTree.Children(n).Children) % Delete prior subnodes
                        delete(app.DataExportTree.Children(n).Children(1));
                    end

                    for k = 1:length(app.model.tissue) % Make correct subnodes
                        name = app.model.tissue{k};
                        Node = uitreenode(app.DataExportTree.Children(n));
                        Node.Text = name;
                    end
                end
            end
        end

        function [outerLayer, idx] = findOuterLayer(app)
            %%  Function that finds the outer layer / index of the outer layer of the stl meshes imported
            %   The method used is to find the layer that is enclosed by
            %   none of the other stl layers (ie enclosed by free space)

            for i = 1:size(app.meshEncTissues,2)

                encTissue = app.meshEncTissues{i};

                if ~any(strcmp(app.meshInternalNames, encTissue))
                    idx = i;
                    outerLayer = app.meshInternalNames{i};
                end
            end
        end

        function changeselectedplane(app)
            %   Updates the plane's controls to reflect the settings
            %   defined for the plane at app.planes{app.selectedplaneidx}
            idx = app.selectedplaneidx;

            app.PlanesDropDown.Value = idx;
            app.PlaneNameEditField.Value = app.planes{idx}.name; % converting from m to mm
            app.DirectionDropDown.Value = app.planes{idx}.direction; % converting from m to mm
            app.XCoordEditField.Value = app.planes{idx}.position(1)*1e3; % converting from m to mm
            app.YCoordEditField.Value = app.planes{idx}.position(2)*1e3; % converting from m to mm
            app.ZCoordEditField.Value = app.planes{idx}.position(3)*1e3; % converting from m to mm
            app.PlaneWidthEditField.Value = app.planes{idx}.width*1e3; % converting from m to mm
            app.CoilDisplayVisibilitySwitch.Value = app.planes{idx}.visibility;

            %   Update display to reflect the new tab
            updatePlanesForPlanningTab(app);

        end

        function updateplanedropdown(app)

            %   assign the planes drop down menu the names of the currently
            %   created planes and assign the values to correspond with the
            %   index of the plane in app.planes
            app.PlanesDropDown.Items = {}; % clear to make sure that there are not too many items
            for (i = 1:length(app.planes))
                app.PlanesDropDown.Items{i} = app.planes{i}.name; % assign the names of the items
                app.PlanesDropDown.ItemsData{i} = i; % set the data to be the index of the selected
                % item in the planes drop down menu (same as the index of the plane in app.planes)
            end

        end

        function updatelinedropdown(app)

            %   assign the lines drop down menu the names of the currently
            %   created lines and assign the values to correspond with the
            %   index of the line in app.lines
            app.LinesDropDown.Items = {}; % clear to make sure that there are not too many items
            for (i = 1:length(app.lines))
                app.LinesDropDown.Items{i} = app.lines{i}.name; % assign the names of the items
                app.LinesDropDown.ItemsData{i} = i; % set the data to be the index of the selected
                % item in the planes drop down menu (same as the index of the plane in app.planes)
            end

        end

        function changeselectedline(app)
            %   Updates the lines's controls to reflect the settings
            %   defined for the line at app.planes{app.selectedplaneidx}
            idx = app.selectedlineidx;

            app.LinesDropDown.Value = idx;
            app.LineNameEditField.Value = app.lines{idx}.name;
            app.XVectorDirectionEditField.Value = app.lines{idx}.direction(1);
            app.YVectorDirectionEditField.Value = app.lines{idx}.direction(2);
            app.ZVectorDirectionEditField.Value = app.lines{idx}.direction(3);
            app.LineLengthEditField.Value = app.lines{idx}.length*1e3; % from m to mm
            app.XCoordLineEditField.Value = app.lines{idx}.position(1)*1e3; % from m to mm
            app.YCoordLineEditField.Value = app.lines{idx}.position(2)*1e3; % from m to mm
            app.ZCoordLineEditField.Value = app.lines{idx}.position(3)*1e3; % from m to mm
            app.CoilDisplayLineVisibilitySwitch.Value = app.lines{idx}.visibility;

            %   Update display to reflect the new tab
            displayobslines(app);

        end

        function updateCoilDataStruct(app)
            % Updates the coil data struct with the new coil values
            if (~isempty(app.LoadedCoilPositionsListBox.Items))
                value = app.LoadedCoilPositionsListBox.Value;
                matrix = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value, app.MatrixField14.Value;
                    app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value, app.MatrixField24.Value;
                    app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value, app.MatrixField34.Value;
                    app.MatrixField41.Value, app.MatrixField42.Value, app.MatrixField43.Value, app.MatrixField44.Value];
                app.matrices{value} = matrix;
            end
        end

        function runTest(app)
            event = 0; % placeholder
            oldDir = pwd; %saves prior directory
            modelsFolder = [app.dir app.slash 'Model' app.slash 'Connectome'];
            cd(modelsFolder);

            models = {'Model 110411', 'Model 101309', 'Model 117122', 'Model 120111', 'Model 122317', 'Model 122620', 'Model 124422', 'Model 128632', 'Model 130013'};

            targetPoints = {[-31.148, 0.916, 54.061],
                [-32.817, 4.924, 52.931],
                [-17.653, -0.123, 58.269],
                [-19.804, -2.289, 58.526],
                [-22.047, -0.031, 58.189],
                [-33.541, 0.301, 52.302],
                [-14.256, -3.714, 59.496],
                [-17.299, -2.055, 60.091],
                [-24.654, 2.578, 56.491]};

            coilPositions = {
                [0.9001918819266496, 0.05618748272421517, -0.43185361234852837, -38.40477768942074;
                0.05618748272421517, 0.9683689736283393, 0.2431141659778649, 5.001239546928879;
                0.43185361234852837, -0.2431141659778649, 0.8685608555549889, 68.65611477562639],

                [0.9029168623519691, 0.0887324634827213, -0.42055640478390394, -39.499816961217014;
                0.0887324634827213, 0.918899921588266, 0.38438195070706177, 11.031989773906188;
                0.42055640478390394, -0.38438195070706177, 0.821816783940235, 65.99001201421632],

                [0.9053979373885462, 0.030347544915459015, -0.42347798229680167, -25.01594385042428;
                0.030347544915459015, 0.9902647631883208, 0.13584817004723718, 2.238970374033898;
                0.42347798229680167, -0.13584817004723718, 0.895662700576867, 73.84174391811197],

                [0.9465776022116154, 0.017173200632733265, -0.32201851526167996, -25.967468730716636;
                0.017173200632733265, 0.9944794911463852, 0.10351629277198196, -0.30768711461715265;
                0.32201851526167996, -0.10351629277198196, 0.9410570933580005, 76.53793314619739],

                [0.9519760868728778, 0.04623007664818117, -0.30266203930342234, -26.509811984236364;
                0.04623007664818117, 0.9554967546847056, 0.2913567088640637, 4.265112637708992;
                0.30266203930342234, -0.2913567088640637, 0.9074728415575835, 71.56986759077229],

                [0.8576991679853756, 0.05943970049069317, -0.5107044734900714, -41.64914299592908;
                0.05943970049069317, 0.9751717685384987, 0.2133235660940287, 3.6878079644411823;
                0.5107044734900714, -0.2133235660940287, 0.8328709365238743, 65.52498315567865],

                [0.967712355183179, 0.019260832503791556, -0.25132015032043453, -19.80772165270233;
                0.019260832503791556, 0.9885101663238738, 0.14992221785181914, -0.40218269443226434;
                0.25132015032043453, -0.14992221785181914, 0.9562225215070527, 80.61918200782439],

                [0.9224840983378462, 0.03167327193068778, -0.38473353396736254, -25.319979865930755;
                0.03167327193068778, 0.987058188922737, 0.1572034844581099, 1.2224007783777526;
                0.38473353396736254, -0.1572034844581099, 0.9095422872605832, 79.0532679835042],

                [0.9508866651818317, 0.03128988091425175, -0.30795371946405675, -28.622647490224196;
                0.03128988091425175, 0.9800653600238549, 0.19619590575158194, 5.106407159063907;
                0.30795371946405675, -0.19619590575158194, 0.9309520252056867, 68.48832357440452]};

            trialNames = {'0 Shrunk', '1 Shrunk', '2 Shrunk', '3 Shrunk', '4 Shrunk', '5 Shrunk', '6 Shrunk', '7 Shrunk', '8 Shrunk', '9 Shrunk', '10 Shrunk'};
            trialValues = [0, 1, 2, 3, 4, 5, 6, 7, 8, 9, 10];


            mainFolder = [app.dir app.slash 'Model Solutions'];
            mkdir(mainFolder)

            niftiPath = [app.dir app.slash 'Model'];
            niftiFile = 'T1w.nii';

            coilPath = [app.dir app.slash 'Coil'];
            coilName = 'coil03_MagVenture_MRiB91.m';

            %% Do Initial NIfTI setup
            %   Load nifti data
            %[file,path] = uigetfile('*.nii', 'Select one or more nifti files', [ app.dir app.slash 'Model' ], 'MultiSelect', 'on');

            file = niftiFile;
            path = niftiPath;

            addpath(genpath(path));

            n_files = numel(cellstr(file));
            app.nifti_filepath = cell(1,n_files);

            %   Change values in the table

            for k = 1:n_files
                if n_files==1
                    fileOut = file;
                else
                    fileOut = file{k};
                end

                %   Update table with file name
                if k == 1
                    app.UITable2.Data = {fileOut};
                else
                    app.UITable2.Data = [app.UITable2.Data; {fileOut}];
                end

                %                  %   Read Nifti Data
                app.nifti_filepath{k} = cell2mat(app.UITable2.Data(k,1));
                %
                %                  if exist(app.nifti_filepath{k}, 'file')
                %                      app.niftidata.VT1         = niftiread(app.nifti_filepath{k});
                %                      app.niftidata.info        = niftiinfo(app.nifti_filepath{k});
                %                  end

            end

            app.niftidata.VT1         = niftiread(app.nifti_filepath{1});
            app.niftidata.info        = niftiinfo(app.nifti_filepath{1});

            %% Do initial coil selection

            %   Load coil from existing ones
            %             [file,path] = uigetfile('*.m', 'Select one .m file from Coil Folder', [ app.dir app.slash 'Coil' ]);

            file = coilName;
            path = coilPath;

            addpath(genpath(path));

            %   Update table with file name
            app.UITable3.Data = {file};

            run(file);

            app.strcoil = strcoil;
            app.coilCAD.P = P;
            app.coilCAD.t = t;
            app.coilCAD.tind = tind;
            app.isDipoleCoil = false;


            %% Set exporting data
            app.DataExportTree.CheckedNodes = [app.DataExportTree.Children(3) app.DataExportTree.Children(4)];

            %% Set the solver options
            app.MaximumIterationsEditField.Value = 20;
            app.NumberOfThreadsForProcessingEditField.Value = 48;

            %% Iterate through the models

            for i = 1:length(models)
                for j = 1:1%length(trialNames)

                    cd([models{i} '_shrunk' num2str(trialValues(j))]);
                    folderContains = dir('*.stl');
                    file = cell(0);
                    for k = 1:length(folderContains)
                        file{k} = folderContains(k).name;
                    end

                    path = pwd;

                    % going into this code, path is a string storing the path
                    % to the parent folder, and file is a cell array containing
                    % the names of the selected stl files

                    %% ***** CODE TAKEN FROM 'Select STLs' BUTTON CALLBACK TO BYPASS THE BUTTON *****

                    n_files = numel(cellstr(file));

                    %   Initialize cell arrays
                    app.meshConductivities = cell(1,n_files);
                    app.meshPath = cell(1,n_files);
                    app.meshInternalNames = cell(1,n_files);
                    app.meshList = cell(1,n_files);
                    app.meshEncTissues = cell(1,n_files);
                    app.meshFileNames = cell(1,n_files);


                    %   Change values in the table
                    for k = 1:n_files
                        if n_files==1
                            fileOut = file;
                        else
                            fileOut = file{k};
                        end

                        %  Parse common tissue name from filename
                        %  Expression is the list of tissue names that the function will
                        %  search for in the filename.
                        %  More names can be added following the same format
                        expression = 'bone|skull|csf|eyes|gm|skin|ventricles|wm|cerebellum';

                        %  regexpi: Case-insensitive regex search for a tissue name in fileOut.
                        %  lower: forces lowercase output for consistency
                        %  regFileOut: Cell array with the tissue name from fileOut
                        regFileOut = lower(regexpi(fileOut,expression,'match'));

                        %  regFileChar: Char array for use in switch statement
                        regFileChar = char(regFileOut);

                        %  Switch statement takes regFileChar and assigns default
                        %  name, conductivity, and enclosing tissue
                        %  This list of cases can be expanded to accomodate more
                        %  tissue names
                        switch regFileChar
                            case 'bone', nameFile{k} = 'Skull'; conductivity{k} = 0.010; enclosingTissue{k} = 'Skin';  %  \/
                            case 'skull', nameFile{k} = 'Skull'; conductivity{k} = 0.010; enclosingTissue{k} = 'Skin';  %   /\ Treating bone and skull the same
                            case 'csf', nameFile{k} = 'CSF'; conductivity{k} = 1.654 ; enclosingTissue{k} = 'Skull';
                            case 'eyes', nameFile{k} = 'Eyes'; conductivity{k} = 1.654; enclosingTissue{k} = 'WM';  %  Default conductivity might not be right!!
                            case 'gm', nameFile{k} = 'GM'; conductivity{k} = 0.2750; enclosingTissue{k} = 'CSF';
                            case 'skin', nameFile{k} = 'Skin'; conductivity{k} = 0.4650; enclosingTissue{k} = 'FreeSpace';
                            case 'ventricles', nameFile{k} = 'Ventricles'; conductivity{k} = 1.654; enclosingTissue{k} = 'WM';
                            case 'wm', nameFile{k} = 'WM'; conductivity{k} = 0.1260; enclosingTissue{k} = 'GM';
                            case 'cerebellum', nameFile{k} = 'Cerebellum'; conductivity{k} = 0.1260; enclosingTissue{k} = 'CSF';
                            otherwise, nameFile{k} = '[EnterName]'; conductivity{k} = '[EnterConductivity]'; enclosingTissue{k} = '[EnterEnclosingTissue]';
                        end

                        if k == 1
                            app.UITable.Data = {int2str(k) path fileOut nameFile{k} conductivity{k} enclosingTissue{k}};
                        else
                            app.UITable.Data = [app.UITable.Data; {int2str(k) path fileOut nameFile{k} conductivity{k} enclosingTissue{k}}];
                        end
                    end

                    %% ***********************************************************************************************************

                    modelPath = [mainFolder app.slash models{i}];
                    mkdir(modelPath);

                    %% PRIOR TRIAL

                    ProceedafterSelectionButtonPushed(app, event);

                    % Place the coil
                    app.MatrixField11.Value = coilPositions{i}(1,1);
                    app.MatrixField12.Value = coilPositions{i}(1,2);
                    app.MatrixField13.Value = coilPositions{i}(1,3);
                    app.MatrixField14.Value = coilPositions{i}(1,4);
                    app.MatrixField21.Value = coilPositions{i}(2,1);
                    app.MatrixField22.Value = coilPositions{i}(2,2);
                    app.MatrixField23.Value = coilPositions{i}(2,3);
                    app.MatrixField24.Value = coilPositions{i}(2,4);
                    app.MatrixField31.Value = coilPositions{i}(3,1);
                    app.MatrixField32.Value = coilPositions{i}(3,2);
                    app.MatrixField33.Value = coilPositions{i}(3,3);
                    app.MatrixField34.Value = coilPositions{i}(3,4);
                    MatrixFieldValueChanged(app, event);

                    %                     app.PointXValEditField.Value = targetPoints{i}(1);
                    %                     app.PointYValEditField.Value = targetPoints{i}(2);
                    %                     app.PointZValEditField.Value = targetPoints{i}(3);
                    %                     UpdateDisplayButtonPushed(app, event);

                    StartProcessingButtonPushed(app, event);


                    trialPath = [modelPath app.slash trialNames{j}];
                    mkdir(trialPath);
                    exportData(app, trialPath);

                    cd ..
                end
            end

            %             for i = 1:length(trials)
            %                 dist = (i-1)*0.75;
            %                 app.CoilSkinDistanceEditField.Value = dist;
            %                 UpdateDisplayButtonPushed(app, event);
            %                 StartProcessingButtonPushed(app, event);
            %                 filePath = [mainFolder app.slash trials{i}];
            %                 mkdir(filePath);
            %                 exportData(app, filePath);
            %             end

            cd(oldDir); %restores directory
        end
    end

    methods (Access = public)
        function throwErrorPopup(app, message, title)
            errordlg(message, title);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %   Adds the pathes for the required scripts
            addpathes(app);

            % Create tabs with additionalUIInit
%             additionalUIInit(app);
%             resizeText(app);

            %   Turns hold on for both displays
            hold(app.CrossSectionDisplay, "on");
            hold(app.CoilDisplay, "on");
            hold(app.SolverDisplay, "on");
            hold(app.CoilPPDisplay, "on");
            hold(app.CrossSectionPPDisplay, "on");
            hold(app.SurfaceDisplay, "on");

            %   Scale Matrix (m to cm)
            scaleMatrix = [100 0 0;
                0 100 0;
                0 0 100];

            %sets a few properties of the CoilDisplay axis
            grid(app.CoilDisplay,'on');
            lim = app.ScalemmSlider.Value;
            app.CoilDisplay.XLim = [-lim, lim];
            app.CoilDisplay.YLim = [-lim, lim];
            app.CoilDisplay.ZLim = [-lim, lim];

            %repeats for the SurfaceDisplay axis
            lim = app.ScaleSurfaceSlider.Value;
            app.SurfaceDisplay.XLim = [-lim, lim];
            app.SurfaceDisplay.YLim = [-lim, lim];
            app.SurfaceDisplay.ZLim = [-lim, lim];

            %   Sets the update function for clicking points on the
            %   CoilDisplay axes
            datacursormode(app.UIFigure, 'on');
            app.dcm_obj = datacursormode(app.UIFigure);
            set(app.dcm_obj,'UpdateFcn',@pointClickUpdateFunction);

            % Initializes the struct elements
            app.niftidisplaydata.userpoint = [];
            app.niftidisplaydata.coilcenterline = [];
            app.niftidisplaydata.centerlineintersection = [];
            app.niftidisplaydata.fieldplane = [];

            app.niftidisplaydatapp.userpoint = [];
            app.niftidisplaydatapp.coilcenterline = [];
            app.niftidisplaydatapp.centerlineintersection = [];
            app.niftidisplaydatapp.fieldplane = [];

            app.CoilDisplayObjects.coilpatch = [];
            app.CoilDisplayObjects.brainpatch = [];
            app.CoilDisplayObjects.light = [];
            app.CoilDisplayObjects.coilNormalLine = [];
            app.CoilDisplayObjects.coilFieldLine = [];
            app.CoilDisplayObjects.planes = cell(0);
            app.CoilDisplayObjects.lines = cell(0);


        end

        % Value changed function: MatrixField11, MatrixField12, 
        % MatrixField13, MatrixField14, MatrixField21, MatrixField22, 
        % MatrixField23, MatrixField24, MatrixField31, MatrixField32, 
        % MatrixField33, MatrixField34, MatrixField41, MatrixField42, 
        % MatrixField43, MatrixField44
        function MatrixFieldValueChanged(app, event)
            %   Updates the matrix data to the data structure
            updateCoilDataStruct(app);
            %   displays the coil to the coil display
            updateCoilToCoilDisplay(app)
            %   updates the coil's normal and
            updateCoilToCrossSectionDisplay(app);
        end

        % Button pushed function: ResetRotationButton_3, 
        % ResetRotationButton_4
        function ResetRotationButtonPushed(app, event)

            %   Restore rotation matrix to identity matrix
            app.MatrixField11.Value = 1;
            app.MatrixField12.Value = 0;
            app.MatrixField13.Value = 0;
            app.MatrixField21.Value = 0;
            app.MatrixField22.Value = 1;
            app.MatrixField23.Value = 0;
            app.MatrixField31.Value = 0;
            app.MatrixField32.Value = 0;
            app.MatrixField33.Value = 1;

            %   Updates the matrix data to the data structure
            updateCoilDataStruct(app);
            %   displays the coil to the coil display
            updateCoilToCoilDisplay(app)
            %   updates the coil's normal and 
            updateCoilToCrossSectionDisplay(app);
        end

        % Value changed function: ScalemmSlider
        function ScalemmSliderValueChanged(app, event)
            value = app.ScalemmSlider.Value;

            app.CoilDisplay.XLim = [-value,value];
            app.CoilDisplay.YLim = [-value,value];
            app.CoilDisplay.ZLim = [-value,value];

        end

        % Button pushed function: ResetTranslationButton_3, 
        % ResetTranslationButton_4
        function ResetTranslationButtonPushed(app, event)

            %   Restore translation matrix
            app.MatrixField14.Value = 0;
            app.MatrixField24.Value = 0;
            app.MatrixField34.Value = 80;

            %   Updates the matrix data to the data structure
            updateCoilDataStruct(app);
            %   displays the coil to the coil display
            updateCoilToCoilDisplay(app)
            %   updates the coil's normal and 
            updateCoilToCrossSectionDisplay(app);
        end

        % Callback function
        function HeadCompartmentsButtonGroupSelectionChanged(app, event)
            %selectedButton = app.HeadCompartmentsButtonGroup.SelectedObject;

            %   Update visuals
            updateBrainToCoilDisplay(app);

        end

        % Value changed function: FieldVectorSwitch, VectorfromcoilSwitch
        function VectortocoilSwitchValueChanged(app, event)
            %   displays the coil to the coil display
            updateCoilToCoilDisplay(app)
            %   updates the coil's normal and 
            updateCoilToCrossSectionDisplay(app);
        end

        % Button pushed function: UpdateDisplayButton
        function UpdateDisplayButtonPushed(app, event)

            point = [app.PointXValEditField.Value, app.PointYValEditField.Value, app.PointZValEditField.Value] .* 1e-3; % convert coords to m


            %   Find centers, normal vectors, and t on the skin
            Center = app.mesh.Center;
            normals = app.mesh.normals;
            Indicator = app.mesh.Indicator;
            P = app.mesh.P;
            t = app.mesh.t;


            %%  How to find the outside layer (Skin) rather than the first item
            [~, idx] = findOuterLayer(app);


            eligibleCenters = Center(Indicator==idx,:);
            eligibleNormals = normals(Indicator==idx,:);
            eligibleT = t(Indicator==idx,:);

            %   Find the index of the closest point (center of triangle) on the skin
            Idx1 = knnsearch(eligibleCenters, point);

            %   Find the normals of chosen triangle and its neighbors and
            %   average them together
            DT = triangulation(eligibleT,P);
            tNeighbor = neighbors(DT);
            trianglesOfInterest = [Idx1 tNeighbor(Idx1,:)];
            normalsOfInterest = eligibleNormals(trianglesOfInterest,:);
            selectedNormal = mean(normalsOfInterest)/norm(mean(normalsOfInterest));
            app.directionNVector = selectedNormal;


            %   Find the distance from the user's point to it's
            %   intersection with the skin
            intersectionDistance = meshsegtrintersection(point, selectedNormal, 1, P, eligibleT);
            if (any(isnan(intersectionDistance)))
                error('Something went terribly wrong');
            end
            intersectionDistance = intersectionDistance(intersectionDistance~=0);

            %   If there is no intersection with the skin from the user
            %   selected point in the direction of the skin's normal then
            %   you are already on the skin (if on the model) so use the
            %   user's point as the skinpoint
            if (isempty(intersectionDistance))

                app.skinPoint = point;

            else

                %   Find the point of intersection with the skin
                app.skinPoint = point + intersectionDistance*selectedNormal;
            end

            %   Translate the coil
            translations = app.skinPoint + app.directionNVector .* (app.CoilSkinDistanceEditField.Value * 1e-3);   %app.CoilSkinDistanceEditField.Value in mm
            translations = translations .* 1e3; % convert translations from m to mm
            app.MatrixField14.Value = translations(1);
            app.MatrixField24.Value = translations(2);
            app.MatrixField34.Value = translations(3);


            %   Rotate the coil to face opposite the selected normal vector
            angle = 0;
            a = [0,0,-1];   % Vector we are starting at
            b = selectedNormal*(-1); % Vector we want
            v = cross(a, b);
            s = norm(cross(a,b)) * sin(angle);
            c = dot(a,b)*cos(angle);
            vCrossMatrix = [0, -v(3), v(2); v(3), 0, -v(1);-v(2), v(1), 0];

            rotationMatrix = eye(3) + vCrossMatrix + vCrossMatrix^2 * 1/(1+c);
            app.MatrixField11.Value = rotationMatrix(1,1);
            app.MatrixField12.Value = rotationMatrix(1,2);
            app.MatrixField13.Value = rotationMatrix(1,3);
            app.MatrixField21.Value = rotationMatrix(2,1);
            app.MatrixField22.Value = rotationMatrix(2,2);
            app.MatrixField23.Value = rotationMatrix(2,3);
            app.MatrixField31.Value = rotationMatrix(3,1);
            app.MatrixField32.Value = rotationMatrix(3,2);
            app.MatrixField33.Value = rotationMatrix(3,3);

            %   Updates the matrix data to the data structure
            updateCoilDataStruct(app);
            %   displays the coil to the coil display
            updateCoilToCoilDisplay(app);
            %   updates the coil's normal and 
            updateCoilToCrossSectionDisplay(app);

        end

        % Button pushed function: UpdateTargetButton
        function UpdateTargetButtonPushed(app, event)
            try
                %   Updates point param to edit fields
                info_struct = getCursorInfo(app.dcm_obj);
                if (isfield(info_struct, 'Position') && length(info_struct.Position) == 3)
                    point = info_struct.Position;

                    app.PointXValEditField.Value = round(point(1), 3);
                    app.PointYValEditField.Value = round(point(2), 3);
                    app.PointZValEditField.Value = round(point(3), 3);

                    % Update user's point on the coildisplay
                    delete(app.userpointobj);
                    app.userpointobj = plot3(app.CoilDisplay, app.PointXValEditField.Value, app.PointYValEditField.Value, app.PointZValEditField.Value, Color = 'green', Marker= '*', MarkerSize=10);

                    % Update user's point on the crosssectiondisplay
                    if (~isempty(app.planes))
                        delete(app.niftidisplaydata.userpoint);
                        app.niftidisplaydata.userpoint = addpointto2Ddisplay(app.CrossSectionDisplay, [app.PointXValEditField.Value, app.PointYValEditField.Value, app.PointZValEditField.Value], app.planes{app.selectedplaneidx}.direction);
                    end

                elseif(isfield(info_struct, 'Position') && length(info_struct.Position) == 2)
                    point = info_struct.Position .* 0.1; %Convert from mm to cm

                    switch app.planes{app.selectedplaneidx}{2}
                        case 'xy'
                            app.PointXValEditField.Value = round(point(1), 3);
                            app.PointYValEditField.Value = round(point(2), 3);
                            app.PointZValEditField.Value = round(app.planes{app.selectedplaneidx}.position(3), 3);
                        case 'xz'
                            app.PointXValEditField.Value = round(point(1), 3);
                            app.PointYValEditField.Value = round(app.planes{app.selectedplaneidx}.position(2), 3);
                            app.PointZValEditField.Value = round(point(2), 3);
                        case 'yz'
                            app.PointXValEditField.Value = round(app.planes{app.selectedplaneidx}.position(1), 3);
                            app.PointYValEditField.Value = round(point(1), 3);
                            app.PointZValEditField.Value = round(point(2), 3);

                    end

                    % Update user's point on the coildisplay
                    delete(app.userpointobj);
                    app.userpointobj = plot3(app.CoilDisplay, app.PointXValEditField.Value, app.PointYValEditField.Value, app.PointZValEditField.Value, Color = 'green', Marker= '*', MarkerSize=10);

                    % Update user's point on the crosssectiondisplay
                    if (~isempty(app.planes))
                        delete(app.niftidisplaydata.userpoint);
                        app.niftidisplaydata.userpoint = addpointto2Ddisplay(app.CrossSectionDisplay, [app.PointXValEditField.Value, app.PointYValEditField.Value, app.PointZValEditField.Value], app.planes{app.selectedplaneidx}.direction);
                    end
                end
            catch
                throwErrorPopup(app, "Please make sure that only one point is selected. If you have more than one point selected, you can remove them using the Remove Points button.", "Error");
            end
        end

        % Value changed function: TransformationMatrixSwitch
        function TransformationMatrixSwitchValueChanged(app, event)
            value = app.TransformationMatrixSwitch.Value;
            switch value
                case 'Off'

                    %   Disables visibility of the Transformation Matrix
                    app.CoilTransformationMatrixPanel.Visible = "off";

                case 'On'

                    %   Enables visibility of the Transformation Matrix
                    app.CoilTransformationMatrixPanel.Visible = "on";
            end

        end

        % Value changed function: HeadCompartmentsDropDown
        function HeadCompartmentsDropDownValueChanged(app, event)

            %   Updates the display. The checking is handled in
            %   AppFunctions/displaybrain.m
            updateBrainToCoilDisplay(app);
        end

        % Callback function
        function CrossSectionControlsTabGroupSelectionChanged(app, event)

            %   Update the image
            updatePlanesForPlanningTab(app);
        end

        % Callback function
        function LoadCurrentBasedCoilButtonPushed(app, event)
%             try
%                 f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
% 
%                 %   Save file and path of the opened documents
%                 [file,path] = uigetfile('*.stl', 'Select one or more stl files', [ app.dir app.slash 'Model' app.slash ], 'MultiSelect', 'on');
% 
%                 delete(f); %delete the dummy figure
% 
%                 n_files = numel(cellstr(file));
% 
%                 %   Initialize cell arrays
%                 app.meshConductivities = cell(1,n_files);
%                 app.meshPath = cell(1,n_files);
%                 app.meshInternalNames = cell(1,n_files);
%                 app.meshList = cell(1,n_files);
%                 app.meshEncTissues = cell(1,n_files);
%                 app.meshFileNames = cell(1,n_files);
% 
%                 %   Change values in the table
%                 for k = 1:n_files
%                     if n_files==1
%                         fileOut = file;
%                     else
%                         fileOut = file{k};
%                     end
% 
%                     %  Parse common tissue name from filename
%                     %  Expression is the list of tissue names that the function will
%                     %  search for in the filename.
%                     %  More names can be added following the same format
%                     expression = 'bone|skull|csf|eyes|gm|skin|ventricles|wm|cerebellum';
% 
%                     %  regexpi: Case-insensitive regex search for a tissue name in fileOut.
%                     %  lower: forces lowercase output for consistency
%                     %  regFileOut: Cell array with the tissue name from fileOut
%                     regFileOut = lower(regexpi(fileOut,expression,'match'));
% 
%                     %  regFileChar: Char array for use in switch statement
%                     regFileChar = char(regFileOut);
% 
%                     %  Switch statement takes regFileChar and assigns default
%                     %  name, conductivity, and enclosing tissue
%                     %  This list of cases can be expanded to accomodate more
%                     %  tissue names
%                     switch regFileChar
%                         case 'bone', nameFile{k} = 'Skull'; conductivity{k} = 0.010; enclosingTissue{k} = 'Skin';  %  \/
%                         case 'skull', nameFile{k} = 'Skull'; conductivity{k} = 0.010; enclosingTissue{k} = 'Skin';  %   /\ Treating bone and skull the same
%                         case 'csf', nameFile{k} = 'CSF'; conductivity{k} = 1.654 ; enclosingTissue{k} = 'Skull';
%                         case 'eyes', nameFile{k} = 'Eyes'; conductivity{k} = 1.654; enclosingTissue{k} = 'WM';  %  Default conductivity might not be right!!
%                         case 'gm', nameFile{k} = 'GM'; conductivity{k} = 0.2750; enclosingTissue{k} = 'CSF';
%                         case 'skin', nameFile{k} = 'Skin'; conductivity{k} = 0.4650; enclosingTissue{k} = 'FreeSpace';
%                         case 'ventricles', nameFile{k} = 'Ventricles'; conductivity{k} = 1.654; enclosingTissue{k} = 'WM';
%                         case 'wm', nameFile{k} = 'WM'; conductivity{k} = 0.1260; enclosingTissue{k} = 'GM';
%                         case 'cerebellum', nameFile{k} = 'Cerebellum'; conductivity{k} = 0.1260; enclosingTissue{k} = 'CSF';
%                         otherwise, nameFile{k} = '[EnterName]'; conductivity{k} = '[EnterConductivity]'; enclosingTissue{k} = '[EnterEnclosingTissue]';
%                     end
% 
%                     if k == 1
%                         app.UITable.Data = {int2str(k) path fileOut nameFile{k} conductivity{k} enclosingTissue{k}};
%                     else
%                         app.UITable.Data = [app.UITable.Data; {int2str(k) path fileOut nameFile{k} conductivity{k} enclosingTissue{k}}];
%                     end
%                 end
%             end

        end

        % Callback function
        function LoadCurrentBasedCoilButton_2Pushed(app, event)
% %             try
%                 prevCd = cd;
%                 cd(app.dir);
%                 % Turn status lamp color to orange
%                 app.ImportStatusLamp.Color = [1,1,0];
%                 pause(0.5);
% 
%                 %   Save data from the table in cell arrays
%                 for i = 1:size(app.UITable.Data,1)
% 
%                     app.meshPath{i} = cell2mat(app.UITable.Data(i,2));
%                     app.meshFileNames{i} = cell2mat(app.UITable.Data(i,3));
%                     app.meshList{i} = stlread(cell2mat(app.UITable.Data(i,3)));
%                     app.meshInternalNames{i} = cell2mat(app.UITable.Data(i,4));
%                     app.meshConductivities{i} = cell2mat(app.UITable.Data(i,5));
%                     app.meshEncTissues{i} = cell2mat(app.UITable.Data(i,6));
% 
%                 end
% 
% 
%                 %   Sets up resources for the brain model
%                 modelProcessor(app);
% 
%                 app.mesh = load('CombinedMesh.mat');
% 
%                 app.WindowTabGroup.SelectedTab = app.WindowTabGroup.Children(2);    % Sets the current tab to the second tab in the tab group (Planning tab)
% 
%                 %   Sets values to conform to the imported data
%                 postImportSetup(app);
% 
%                 %   Displays the initial image and creates correct coil
%                 %   data struct
%                 event = 0; % fake event
% 
%                 app.LoadedCoilPositionsListBox.Items = {};
%                 app.LoadedCoilPositionsListBox.ItemsData = []; 
%                 app.matrices = [];
%                 ResetTranslationButtonPushed(app, event); % returns coil to default location
%                 ResetRotationButtonPushed(app, event); % returns coil to default rotation
%                 AddCoilPositionButtonPushed(app, event); % adds default coil position
%                 updateImage(app); % displays the image
% 
%                 % Change status lamp color to green
%                 app.ImportStatusLamp.Color = [0,1,0];
%                 cd(prevCd);
% %             catch
%                 throwErrorPopup(app, 'Error during startup. Please make sure that your stl files, coil, and NIfTI files are imported correctly.', 'Error');
%                 app.ImportStatusLamp.Color = [0.9412    0.9412    0.9412];
%                 cd(prevCd);
%             end
        end

        % Value changed function: PlanesDropDown
        function PlanesDropDownValueChanged(app, event)
            app.selectedplaneidx = app.PlanesDropDown.Value;

            %   Display the values of the selected plane to the controls
            %   (also displays the new plane)
            changeselectedplane(app);
        end

        % Button pushed function: AddNewPlaneButton
        function AddNewPlaneButtonPushed(app, event)
            %   adds a new plane with default values and switches to it

            app.selectedplaneidx = length(app.planes) + 1; % select the new plane
            % create the new plane with default values
            app.planes{app.selectedplaneidx}.name = ['Plane ' num2str(app.selectedplaneidx)];
            app.planes{app.selectedplaneidx}.direction = 'xy';
            app.planes{app.selectedplaneidx}.position = [0, 0, 0];
            app.planes{app.selectedplaneidx}.width = 0.1;
            app.planes{app.selectedplaneidx}.visibility = true;

            updateplanedropdown(app);

            %   Display the values of the selected plane to the controls
            %   (also displays the new plane)
            changeselectedplane(app);

        end

        % Button pushed function: DeletePlaneButton
        function DeletePlaneButtonPushed(app, event)
            if (~isempty(app.planes))

                if (length(app.CoilDisplayObjects.planes) >= app.selectedplaneidx)
                    delete(app.CoilDisplayObjects.planes{app.selectedplaneidx}); % if there is data for the patch
                    % of the plane being deleted then remove the patch from the display
                end

                app.CoilDisplayObjects.planes(app.selectedplaneidx) = []; % fully removes the index in the display objects

                app.planes(app.selectedplaneidx) = []; % deletes the plane's data in the cell array
                app.selectedplaneidx = 1;

                updateplanedropdown(app);

                if (~isempty(app.planes))
                    %   Display the values of the selected plane to the controls
                    %   (also displays the new plane)
                    changeselectedplane(app);
                else
                    app.PlaneNameEditField.Value = "";
                    cla(app.CrossSectionDisplay);
                    app.XCoordEditField.Value = 0;
                    app.YCoordEditField.Value = 0;
                    app.ZCoordEditField.Value = 0;
                    app.PlaneWidthEditField.Value = 0;
                    app.CoilDisplayVisibilitySwitch.Value = "Off";
                end
            end
        end

        % Callback function
        function PlaneNameEditFieldValueChanged(app, event)
            value = app.PlaneNameEditField.Value;

            if (~isempty(app.planes))
                app.planes{app.selectedplaneidx}.name = value;
                updateplanedropdown(app);

                app.PlanesDropDown.Value = app.selectedplaneidx;
            end

        end

        % Button pushed function: CenterOnPointButton
        function CenterOnPointButtonPushed(app, event)
            %   Updates point param to edit fields
            info_struct = getCursorInfo(app.dcm_obj);
            if (isfield(info_struct, 'Position') && length(info_struct.Position) == 3)
                point = info_struct.Position;

                app.XCoordEditField.Value = round(point(1), 3);
                app.YCoordEditField.Value = round(point(2), 3);
                app.ZCoordEditField.Value = round(point(3), 3);

                XCoordcmEditFieldValueChanged(app, event); % event doesn't matter

            elseif(isfield(info_struct, 'Position') && length(info_struct.Position) == 2)
                point = info_struct.Position .* 0.1; %Convert from mm to cm

                switch app.planes{app.selectedplaneidx}.direction
                    case 'xy'
                        app.XCoordEditField.Value = round(point(1), 3);
                        app.YCoordEditField.Value = round(point(2), 3);
                        app.ZCoordEditField.Value = round(app.planes{app.selectedplaneidx}.position(3), 3);
                    case 'xz'
                        app.XCoordEditField.Value = round(point(1), 3);
                        app.YCoordEditField.Value = round(app.planes{app.selectedplaneidx}.position(2), 3);
                        app.ZCoordEditField.Value = round(point(2), 3);
                    case 'yz'
                        app.XCoordEditField.Value = round(app.planes{app.selectedplaneidx}.position(1), 3);
                        app.YCoordEditField.Value = round(point(1), 3);
                        app.ZCoordEditField.Value = round(point(2), 3);

                end
                XCoordcmEditFieldValueChanged(app, event); % event doesn't matter
            end
        end

        % Button pushed function: LoadNiftiDataButton
        function LoadNiftiDataButtonPushed(app, event)
            try
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI

                %   Load nifti data
                [file,path] = uigetfile('*.nii', 'Select one or more nifti files', [ app.dir app.slash 'Model' ], 'MultiSelect', 'on');

                addpath(genpath(path));

                delete(f);

                n_files = numel(cellstr(file));
                app.nifti_filepath = cell(1,n_files);

                %   Change values in the table

                for k = 1:n_files
                    if n_files==1
                        fileOut = file;
                    else
                        fileOut = file{k};
                    end

                    %   Update table with file name
                    if k == 1
                        app.UITable2.Data = {fileOut};
                    else
                        app.UITable2.Data = [app.UITable2.Data; {fileOut}];
                    end

                    %                  %   Read Nifti Data
                    app.nifti_filepath{k} = cell2mat(app.UITable2.Data(k,1));
                    %
                    %                  if exist(app.nifti_filepath{k}, 'file')
                    %                      app.niftidata.VT1         = niftiread(app.nifti_filepath{k});
                    %                      app.niftidata.info        = niftiinfo(app.nifti_filepath{k});
                    %                  end

                end

                app.niftidata.VT1         = niftiread(app.nifti_filepath{1});
                app.niftidata.info        = niftiinfo(app.nifti_filepath{1});
            end

        end

        % Button pushed function: LoadCoilButton
        function LoadCoilButtonPushed(app, event)
            try
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI

                %   Load coil from existing ones
                [file,path] = uigetfile('*.m', 'Select one .m file from Coil Folder', [ app.dir app.slash 'Coil' ]);

                delete(f);

                addpath(genpath(path));

                %   Update table with file name
                app.UITable3.Data = {file};

                run(file);

                app.strcoil = strcoil;
                app.coilCAD.P = P;
                app.coilCAD.t = t;
                app.coilCAD.tind = tind;
                app.isDipoleCoil = false;
            end
        end

        % Value changed function: NiftiDataDropDown
        function NiftiDataDropDownValueChanged(app, event)
            value = app.NiftiDataDropDown.Value;

            %   Read Nifti Data
            %if exist(value, 'file')
            app.niftidata.VT1         = niftiread(value);
            app.niftidata.info        = niftiinfo(value);
            %end
            updateImage(app);

        end

        % Button pushed function: AddNewLineButton
        function AddNewLineButtonPushed(app, event)
            %   adds a new line with default values and switches to it

            app.selectedlineidx = length(app.lines) + 1; % select the new line

            % create the new line with default values (all units m)
            app.lines{app.selectedlineidx}.name = ['Line ' num2str(app.selectedlineidx)];
            app.lines{app.selectedlineidx}.direction = [0, 0, 1];
            app.lines{app.selectedlineidx}.length = 0.1;
            app.lines{app.selectedlineidx}.position = [0, 0, 0];
            app.lines{app.selectedlineidx}.visibility = true;

            updatelinedropdown(app);

            %   Display the values of the selected plane to the controls
            %   (also displays the new plane)
            changeselectedline(app);
        end

        % Button pushed function: DeleteLineButton
        function DeleteLineButtonPushed(app, event)
            if (~isempty(app.lines))
                if (length(app.CoilDisplayObjects.lines) >= app.selectedlineidx)
                    delete(app.CoilDisplayObjects.lines{app.selectedlineidx}); % if there is data for the patch
                    % of the line being deleted then remove the patch from the display
                end

                app.CoilDisplayObjects.lines(app.selectedlineidx) = []; % fully removes the index in the display objects

                app.lines(app.selectedlineidx) = []; % deletes the lines's data in the cell array
                app.selectedlineidx = 1;

                updatelinedropdown(app);

                if (~isempty(app.lines))
                    %   Display the values of the selected line to the controls
                    changeselectedline(app);
                else
                    app.LineNameEditField.Value = '';
                end
            end
        end

        % Button pushed function: CenterLineOnPointButton
        function CenterLineOnPointButtonPushed(app, event)
            %   Updates point param to edit fields
            info_struct = getCursorInfo(app.dcm_obj);
            if (isfield(info_struct, 'Position') && length(info_struct.Position) == 3)
                point = info_struct.Position;

                app.XCoordLineEditField.Value = round(point(1), 3);
                app.YCoordLineEditField.Value = round(point(2), 3);
                app.ZCoordLineEditField.Value = round(point(3), 3);

                XCoordLinecmEditFieldValueChanged(app, event); % event doesn't matter

            elseif(isfield(info_struct, 'Position') && length(info_struct.Position) == 2)
                point = info_struct.Position .* 0.1; %Convert from mm to cm

                switch app.planes{app.selectedplaneidx}{2}
                    case 'xy'
                        app.XCoordLineEditField.Value = round(point(1), 3);
                        app.YCoordLineEditField.Value = round(point(2), 3);
                        app.ZCoordLineEditField.Value = round(app.planes{app.selectedplaneidx}.position(3), 3);
                    case 'xz'
                        app.XCoordLineEditField.Value = round(point(1), 3);
                        app.YCoordLineEditField.Value = round(app.planes{app.selectedplaneidx}.position(2), 3);
                        app.ZCoordLineEditField.Value = round(point(2), 3);
                    case 'yz'
                        app.XCoordLineEditField.Value = round(app.planes{app.selectedplaneidx}.position(1), 3);
                        app.YCoordLineEditField.Value = round(point(1), 3);
                        app.ZCoordLineEditField.Value = round(point(2), 3);

                end
                XCoordLinecmEditFieldValueChanged(app, event); % event doesn't matter
            end
        end

        % Value changed function: LineNameEditField
        function LineNameEditFieldValueChanged(app, event)
            value = app.LineNameEditField.Value;

            if (~isempty(app.lines))
                app.lines{app.selectedlineidx}.name = value;
                updatelinedropdown(app);

                app.LinesDropDown.Value = app.selectedlineidx;
            end


        end

        % Value changed function: LinesDropDown
        function LinesDropDownValueChanged(app, event)
            app.selectedlineidx = app.LinesDropDown.Value;

            %   Display the values of the selected plane to the controls
            %   (also displays the new plane)
            changeselectedline(app);
        end

        % Value changed function: CoilDisplayVisibilitySwitch
        function CoilDisplayVisibilitySwitchValueChanged(app, event)
            if (~isempty(app.planes))
                app.planes{app.selectedplaneidx}.visibility = app.CoilDisplayVisibilitySwitch.Value; % update the value in the table

                if (length(app.CoilDisplayObjects.planes) >= app.selectedplaneidx)
                    delete(app.CoilDisplayObjects.planes{app.selectedplaneidx}) % delete the patch from the coil display
                end
                
                updatePlanesForPlanningTab(app); % redraw the planes
           
            end
        end

        % Value changed function: ZVectorDirectionEditField
        function CoilDisplayLineVisibilitySwitchValueChanged(app, event)
            if (~isempty(app.lines))
                app.lines{app.selectedlineidx}.visibility = app.CoilDisplayLineVisibilitySwitch.Value; % update the value in the table

                delete(app.CoilDisplayObjects.lines{app.selectedlineidx}) % delete said patch from the display
                displayobslines(app);
            end
        end

        % Button pushed function: AddObservationPlanesButton
        function AddObservationPlanesButtonPushed(app, event)
            app.selectedplaneidx = length(app.planes) + 1; % index into the first of the new planes
            point = [app.PointXValEditField.Value, app.PointYValEditField.Value, app.PointZValEditField.Value];

            %   Creates the different planes centered at the user's point
            %   and loads them into the coildisplay
            app.planes{app.selectedplaneidx}.name = 'Generated XY Plane';
            app.planes{app.selectedplaneidx}.direction = 'xy';
            app.planes{app.selectedplaneidx}.position = point*1e-3;
            app.planes{app.selectedplaneidx}.width = 0.1;
            app.planes{app.selectedplaneidx}.visibility = true;
            app.CoilDisplayObjects.planes{app.selectedplaneidx} = displayplanesto3Ddisplay(app.CoilDisplay, app.planes{app.selectedplaneidx}.position*1e3, app.planes{app.selectedplaneidx}.width*1e3, app.planes{app.selectedplaneidx}.direction);

            app.selectedplaneidx = length(app.planes) + 1;
            app.planes{app.selectedplaneidx}.name = 'Generated XZ Plane';
            app.planes{app.selectedplaneidx}.direction = 'xz';
            app.planes{app.selectedplaneidx}.position = point*1e-3;
            app.planes{app.selectedplaneidx}.width = 0.1;
            app.planes{app.selectedplaneidx}.visibility = true;
            app.CoilDisplayObjects.planes{app.selectedplaneidx} = displayplanesto3Ddisplay(app.CoilDisplay, app.planes{app.selectedplaneidx}.position*1e3, app.planes{app.selectedplaneidx}.width*1e3, app.planes{app.selectedplaneidx}.direction);

            app.selectedplaneidx = length(app.planes) + 1;
            app.planes{app.selectedplaneidx}.name = 'Generated YZ Plane';
            app.planes{app.selectedplaneidx}.direction = 'yz';
            app.planes{app.selectedplaneidx}.position = point*1e-3;
            app.planes{app.selectedplaneidx}.width = 0.1;
            app.planes{app.selectedplaneidx}.visibility = true;
            app.CoilDisplayObjects.planes{app.selectedplaneidx} = displayplanesto3Ddisplay(app.CoilDisplay, app.planes{app.selectedplaneidx}.position*1e3, app.planes{app.selectedplaneidx}.width*1e3, app.planes{app.selectedplaneidx}.direction);

            app.selectedplaneidx = app.selectedplaneidx - 2; % revert the selected plane to the first plane created (xy)

            updateplanedropdown(app);
            changeselectedplane(app);

        end

        % Button pushed function: StartProcessingButton
        function StartProcessingButtonPushed(app, event)
            % Change status lamp color yellow
            app.LampStartProcessing.Color = [1,1,0];
            pause(1);

            % Remove unnecessary points
            event = 5;
            RemovePointsButtonPushed(app, event)

            % Run function to do the processing
            processEFieldAllPlanes(app);

            % Run function to plot the currently selected plane
            if (~isempty(app.solvedplanes))
                plotEField(app);
            end

            % Change status lamp color back to green
            app.LampStartProcessing.Color = [0,1,0];
        end

        % Button pushed function: AddObservationLineButton
        function AddObservationLineButtonPushed(app, event)
            app.selectedlineidx = length(app.lines)+1;
            rotMat = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];

            directionNVect = [0, 0, -1];
            newCoilDirection = rotMat * directionNVect';
            newCoilDirection = newCoilDirection';

            app.lines{app.selectedlineidx}.name = 'Generated Coil Centerline';
            app.lines{app.selectedlineidx}.direction = [newCoilDirection(1), newCoilDirection(2), newCoilDirection(3)];
            app.lines{app.selectedlineidx}.length = 0.1;
            app.lines{app.selectedlineidx}.position = [app.MatrixField14.Value*1e-3, app.MatrixField24.Value*1e-3, app.MatrixField34.Value*1e-3];
            app.lines{app.selectedlineidx}.visibility = true;

            updatelinedropdown(app);
            changeselectedline(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
% 
%             %   Save Previous Session
% 
%             %   Save point Data
%             state.skinPoint = app.skinPoint;
%             state.directionNVector = app.directionNVector;
% 
%             %   Save STLs Data
%             state.meshList = app.meshList;
%             state.meshInternalNames = app.meshInternalNames;
%             state.meshConductivities = app.meshConductivities;
%             state.meshPath = app.meshPath;
%             state.meshEncTissues = app.meshEncTissues;
%             state.meshFileNames = app.meshFileNames;
%             state.UITable.Data = app.UITable.Data;
% 
%             %   Save Nifti Data
%             state.nifti_filepath = app.nifti_filepath;
%             state.niftidata = app.niftidata;
%             state.UITable2.Data = app.UITable2.Data;
% 
%             %   Save Coil Data
%             state.coil = app.coil;
%             state.UITable3.Data = app.UITable3.Data;
% 
%             %   Save Transformation Matrix
%             state.MatrixField44.Value = app.MatrixField44.Value;
%             state.MatrixField33.Value = app.MatrixField33.Value;
%             state.MatrixField34.Value = app.MatrixField34.Value;
%             state.MatrixField24.Value = app.MatrixField24.Value;
%             state.MatrixField43.Value = app.MatrixField43.Value;
%             state.MatrixField23.Value = app.MatrixField23.Value;
%             state.MatrixField14.Value = app.MatrixField14.Value;
%             state.MatrixField12.Value = app.MatrixField12.Value;
%             state.MatrixField22.Value = app.MatrixField22.Value;
%             state.MatrixField32.Value = app.MatrixField32.Value;
%             state.MatrixField13.Value = app.MatrixField13.Value;
%             state.MatrixField11.Value = app.MatrixField11.Value;
%             state.MatrixField42.Value = app.MatrixField42.Value;
%             state.MatrixField41.Value = app.MatrixField41.Value;
%             state.MatrixField31.Value = app.MatrixField31.Value;
%             state.MatrixField21.Value = app.MatrixField21.Value;
% 
%             %   Other Saves
%             state.dcm_obj = app.dcm_obj;
%             % state.brainlight = app.brainlight; OUTDATED. ALL OF THE DATA
%             % FOR THIS IS STORED WITHIN app.CoilDisplayObjects
%             state.planes = app.planes;
%             state.selectedplaneidx = app.selectedplaneidx;
%             state.lines = app.lines;
%             state.selectedlineidx = app.selectedlineidx;
% 
%             %   Button Saves
%             state.FieldVectorSwitch.Value = app.FieldVectorSwitch.Value;
%             state.VectorfromcoilSwitch.Value = app.VectorfromcoilSwitch.Value;
%             state.HeadCompartmentsDropDown.Value = app.HeadCompartmentsDropDown.Value;
%             state.NiftiDataDropDown.Value = app.NiftiDataDropDown.Value;
%             state.ScalecmSlider.Value = app.ScalemmSlider.Value;
%             state.TransformationMatrixSwitch.Value = app.TransformationMatrixSwitch.Value;
%             state.PlanesDropDown.Value = app.PlanesDropDown.Value;
% 
%             %   Save everything to .mat file
%             save('prev_session.mat', 'state');
% 
% 
%             %   Delete app
%             delete(app)

        end

        % Button pushed function: LoadPreviousSessionButton
        function LoadPreviousSessionButtonPushed(app, event)
% 
%             %   Load Previous Session
% 
%             if(exist('prev_session.mat'))
%                 load('prev_session.mat');
% 
%                 fNames = fieldnames(state);
% 
%                 for n = 1:length(fNames)
%                     if(contains(fNames{n}, 'UITable') == 1)
%                         app.(fNames{n}).Data = state.(fNames{n}).Data;
%                     elseif(contains(fNames{n}, 'MatrixField') == 1)
%                         app.(fNames{n}).Value = state.(fNames{n}).Value;
%                     elseif ~(strcmp(fNames{n}, 'FieldVectorSwitch') || ...
%                             strcmp(fNames{n}, 'VectorfromcoilSwitch') || ...
%                             strcmp(fNames{n}, 'HeadCompartmentsDropDown') || ...
%                             strcmp(fNames{n}, 'NiftiDataDropDown') || ...
%                             strcmp(fNames{n}, 'ScalecmSlider') || ...
%                             strcmp(fNames{n}, 'TransformationMatrixSwitch') || ...
%                             strcmp(fNames{n}, 'PlanesDropDown'))
%                         app.(fNames{n}) = state.(fNames{n});
%                     end
%                 end
% 
%                 % Turn status lamp color to orange
%                 app.ImportStatusLamp.Color = [1.00,0.41,0.16];
%                 pause(0.5);
% 
%                 %   Save data from the table in cell arrays
%                 for i = 1:size(app.UITable.Data,1)
% 
%                     app.meshPath{i} = cell2mat(app.UITable.Data(i,2));
%                     app.meshFileNames{i} = cell2mat(app.UITable.Data(i,3));
%                     app.meshList{i} = stlread(cell2mat(app.UITable.Data(i,3)));
%                     app.meshInternalNames{i} = cell2mat(app.UITable.Data(i,4));
%                     app.meshConductivities{i} = cell2mat(app.UITable.Data(i,5));
%                     app.meshEncTissues{i} = cell2mat(app.UITable.Data(i,6));
% 
%                 end
% 
%                 %   Sets up resources for the brain model
%                 createTxt(app);
%                 modelProcessor(app);
% 
%                 app.mesh = load('CombinedMesh.mat');
% 
%                 app.WindowTabGroup.SelectedTab = app.WindowTabGroup.Children(2);    % Sets the current tab to the second tab in the tab group (Planning tab)
% 
%                 app.HeadCompartmentsDropDown.Items = app.meshInternalNames;
%                 app.NiftiDataDropDown.Items = app.nifti_filepath;
%                 updateplanedropdown(app);
% 
%                 for n = 1:length(fNames)
%                     if(strcmp(fNames{n}, 'FieldVectorSwitch') || ...
%                             strcmp(fNames{n}, 'VectorfromcoilSwitch') || ...
%                             strcmp(fNames{n}, 'HeadCompartmentsDropDown') || ...
%                             strcmp(fNames{n}, 'NiftiDataDropDown') || ...
%                             strcmp(fNames{n}, 'ScalecmSlider') || ...
%                             strcmp(fNames{n}, 'TransformationMatrixSwitch') || ...
%                             strcmp(fNames{n}, 'PlanesDropDown') == 1)
%                         app.(fNames{n}).Value = state.(fNames{n}).Value;
%                     end
%                 end
% 
%                 TransformationMatrixSwitchValueChanged(app, event);
%                 ScalemmSliderValueChanged(app, event);
% 
%                 %   Displays the initial image
%                 updateImage(app);
%                 %changeselectedplane(app);
% 
% 
%                 % Change status lamp color to green
%                 app.ImportStatusLamp.Color = [0,1,0];
% 
%                 delete('prev_session.mat');
%             end

        end

        % Value changed function: PointXValEditField, PointYValEditField, 
        % PointZValEditField
        function PointXValEditFieldValueChanged(app, event)

            % Update user's point on the coildisplay
            delete(app.userpointobj);
            app.userpointobj = plot3(app.CoilDisplay, app.PointXValEditField.Value, app.PointYValEditField.Value, app.PointZValEditField.Value, Color = 'green', Marker= '*', MarkerSize=10);

            % Update user's point on the crosssectiondisplay
            if (~isempty(app.planes))
                delete(app.niftidisplaydata.userpoint);
                app.niftidisplaydata.userpoint = addpointto2Ddisplay(app.CrossSectionDisplay, [app.PointXValEditField.Value, app.PointYValEditField.Value, app.PointZValEditField.Value], app.planes{app.selectedplaneidx}.direction);
            end

        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI

            %   Save file
            [file,path] = uiputfile('*.mat', 'Save current session in a folder', 'session.mat');

            delete(f);

            %   Save Session

            %   Save point Data
            state.skinPoint = app.skinPoint;
            state.directionNVector = app.directionNVector;

            %   Save STLs Data
            state.meshList = app.meshList;
            state.meshInternalNames = app.meshInternalNames;
            state.meshConductivities = app.meshConductivities;
            state.meshPath = app.meshPath;
            state.meshEncTissues = app.meshEncTissues;
            state.meshFileNames = app.meshFileNames;
            state.UITable.Data = app.UITable.Data;

            %   Save Nifti Data
            state.nifti_filepath = app.nifti_filepath;
            state.niftidata = app.niftidata;
            state.UITable2.Data = app.UITable2.Data;

            %   Save Coil Data
            state.coil = app.coil;
            state.UITable3.Data = app.UITable3.Data;

            %   Save Transformation Matrix
            state.MatrixField44.Value = app.MatrixField44.Value;
            state.MatrixField33.Value = app.MatrixField33.Value;
            state.MatrixField34.Value = app.MatrixField34.Value;
            state.MatrixField24.Value = app.MatrixField24.Value;
            state.MatrixField43.Value = app.MatrixField43.Value;
            state.MatrixField23.Value = app.MatrixField23.Value;
            state.MatrixField14.Value = app.MatrixField14.Value;
            state.MatrixField12.Value = app.MatrixField12.Value;
            state.MatrixField22.Value = app.MatrixField22.Value;
            state.MatrixField32.Value = app.MatrixField32.Value;
            state.MatrixField13.Value = app.MatrixField13.Value;
            state.MatrixField11.Value = app.MatrixField11.Value;
            state.MatrixField42.Value = app.MatrixField42.Value;
            state.MatrixField41.Value = app.MatrixField41.Value;
            state.MatrixField31.Value = app.MatrixField31.Value;
            state.MatrixField21.Value = app.MatrixField21.Value;

            %   Other Saves
            state.dcm_obj = app.dcm_obj;
            % state.brainlight = app.brainlight; OUTDATED. ALL OF THE DATA
            % FOR THIS IS STORED WITHIN app.CoilDisplayObjects 
            state.planes = app.planes;
            state.selectedplaneidx = app.selectedplaneidx;
            state.lines = app.lines;
            state.selectedlineidx = app.selectedlineidx;

            %   Button Saves
            state.FieldVectorSwitch.Value = app.FieldVectorSwitch.Value;
            state.VectorfromcoilSwitch.Value = app.VectorfromcoilSwitch.Value;
            state.HeadCompartmentsDropDown.Value = app.HeadCompartmentsDropDown.Value;
            state.NiftiDataDropDown.Value = app.NiftiDataDropDown.Value;
            state.ScalecmSlider.Value = app.ScalemmSlider.Value;
            state.TransformationMatrixSwitch.Value = app.TransformationMatrixSwitch.Value;
            state.PlanesDropDown.Value = app.PlanesDropDown.Value;

            %   Save everything to .mat file
            newfilename = fullfile(path, file);
            save(newfilename, 'state');

        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)

%             f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
% 
%             %   Load file
%             [file,path] = uigetfile('*.mat', 'Load saved session from a folder');
% 
%             delete(f);
% 
%             %   Load Previous Session
% 
%             if(exist(file))
%                 load(file);
% 
%                 fNames = fieldnames(state);
% 
%                 for n = 1:length(fNames)
%                     if(contains(fNames{n}, 'UITable') == 1)
%                         app.(fNames{n}).Data = state.(fNames{n}).Data;
%                     elseif(contains(fNames{n}, 'MatrixField') == 1)
%                         app.(fNames{n}).Value = state.(fNames{n}).Value;
%                     elseif ~(strcmp(fNames{n}, 'FieldVectorSwitch') || ...
%                             strcmp(fNames{n}, 'VectorfromcoilSwitch') || ...
%                             strcmp(fNames{n}, 'HeadCompartmentsDropDown') || ...
%                             strcmp(fNames{n}, 'NiftiDataDropDown') || ...
%                             strcmp(fNames{n}, 'ScalecmSlider') || ...
%                             strcmp(fNames{n}, 'TransformationMatrixSwitch') || ...
%                             strcmp(fNames{n}, 'PlanesDropDown'))
%                         app.(fNames{n}) = state.(fNames{n});
%                     end
%                 end
% 
%                 % Turn status lamp color to orange
%                 app.ImportStatusLamp.Color = [1.00,0.41,0.16];
%                 pause(0.5);
% 
%                 %   Save data from the table in cell arrays
%                 for i = 1:size(app.UITable.Data,1)
% 
%                     app.meshPath{i} = cell2mat(app.UITable.Data(i,2));
%                     app.meshFileNames{i} = cell2mat(app.UITable.Data(i,3));
%                     app.meshList{i} = stlread(cell2mat(app.UITable.Data(i,3)));
%                     app.meshInternalNames{i} = cell2mat(app.UITable.Data(i,4));
%                     app.meshConductivities{i} = cell2mat(app.UITable.Data(i,5));
%                     app.meshEncTissues{i} = cell2mat(app.UITable.Data(i,6));
% 
%                 end
% 
%                 %   Sets up resources for the brain model
%                 createTxt(app);
%                 modelProcessor(app);
% 
%                 app.mesh = load('CombinedMesh.mat');
% 
%                 app.WindowTabGroup.SelectedTab = app.WindowTabGroup.Children(2);    % Sets the current tab to the second tab in the tab group (Planning tab)
% 
%                 app.HeadCompartmentsDropDown.Items = app.meshInternalNames;
%                 app.NiftiDataDropDown.Items = app.nifti_filepath;
%                 updateplanedropdown(app);
% 
%                 for n = 1:length(fNames)
%                     if(strcmp(fNames{n}, 'FieldVectorSwitch') || ...
%                             strcmp(fNames{n}, 'VectorfromcoilSwitch') || ...
%                             strcmp(fNames{n}, 'HeadCompartmentsDropDown') || ...
%                             strcmp(fNames{n}, 'NiftiDataDropDown') || ...
%                             strcmp(fNames{n}, 'ScalecmSlider') || ...
%                             strcmp(fNames{n}, 'TransformationMatrixSwitch') || ...
%                             strcmp(fNames{n}, 'PlanesDropDown') == 1)
%                         app.(fNames{n}).Value = state.(fNames{n}).Value;
%                     end
%                 end
% 
%                 TransformationMatrixSwitchValueChanged(app, event);
%                 ScalemmSliderValueChanged(app, event);
% 
%                 %   Displays the initial image
%                 updateImage(app);
%                 %changeselectedplane(app);
% 
% 
%                 % Change status lamp color to green
%                 app.ImportStatusLamp.Color = [0,1,0];
% 
%             end

        end

        % Button pushed function: RemovePointsButton
        function RemovePointsButtonPushed(app, event)
            delete(findall(app.UIFigure,'Type','hggroup'));
        end

        % Value changed function: DirectionDropDown, PlaneNameEditField, 
        % PlaneWidthEditField, XCoordEditField, YCoordEditField, 
        % ZCoordEditField
        function XCoordcmEditFieldValueChanged(app, event)
            if (~isempty(app.planes))
                %   Delete the prior patch display for the plane if it
                %   exists
                if (length(app.CoilDisplayObjects.planes) >= app.selectedplaneidx)
                    delete(app.CoilDisplayObjects.planes{app.selectedplaneidx});
                end

                %   Make parameters congruent
                app.planes{app.selectedplaneidx}.direction = app.DirectionDropDown.Value;
                app.planes{app.selectedplaneidx}.position = [app.XCoordEditField.Value*1e-3, app.YCoordEditField.Value*1e-3, app.ZCoordEditField.Value*1e-3]; %converting all params from mm to m for storage
                app.planes{app.selectedplaneidx}.width = app.PlaneWidthEditField.Value*1e-3;

                %   Update the cross-section
                updatePlanesForPlanningTab(app);
            end
        end

        % Value changed function: CoilDisplayLineVisibilitySwitch, 
        % LineLengthEditField, XCoordLineEditField, 
        % XVectorDirectionEditField, YCoordLineEditField, 
        % YVectorDirectionEditField, ZCoordLineEditField
        function XCoordLinecmEditFieldValueChanged(app, event)
            if (~isempty(app.lines))
                %   Delete the prior patch display for the plane if it
                %   exists
                if (length(app.CoilDisplayObjects.lines) >= app.selectedlineidx)
                    delete(app.CoilDisplayObjects.lines{app.selectedlineidx}); % if there is data for the patch
                    % of the line being deleted then remove the patch from the display
                end

                %   Make parameters congruent
                app.lines{app.selectedlineidx}.direction = [app.XVectorDirectionEditField.Value, app.YVectorDirectionEditField.Value, app.ZVectorDirectionEditField.Value]; % converting line length from mm to m
                app.lines{app.selectedlineidx}.length = app.LineLengthEditField.Value*1e-3;
                app.lines{app.selectedlineidx}.position = [app.XCoordLineEditField.Value*1e-3, app.YCoordLineEditField.Value*1e-3, app.ZCoordLineEditField.Value*1e-3]; % converting from mm to m

                %   Update the cross-section
                displayobslines(app);
            end
        end

        % Button pushed function: RedrawPlotButton
        function RedrawPlotButtonPushed(app, event)
            plotEField(app);
        end

        % Value changed function: AdvancedViewCheckBox
        function AdvancedViewCheckBoxValueChanged(app, event)
            bool = app.AdvancedViewCheckBox.Value;
            app.AdvancedOptionsPanel.Visible = bool;
        end

        % Value changed function: XAxisRotationEditField
        function XAxisRotationEditFieldValueChanged(app, event)
            value = app.XAxisRotationEditField.Value;

            %   Reset the rotation slider to be centered at 0
            app.XAxisRotationEditField.Value = 0;

            %   getting the rotational matrix for the specified rotation
            %   around the x-axis
            Rx = [1, 0, 0;
                0, cosd(value), sind(value);
                0, -sind(value), cosd(value)];    %X's rotation matrix

            %   turning the edit fields for the rotation in the matrix into a matrix
            CurrentRot = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];  %current rotation matrix


            %   applying the rotation around the x-axis to the current
            %   rotation matrix
            newRot = Rx*CurrentRot;

            %   Updating all of the fields in the matrix
            app.MatrixField11.Value = newRot(1, 1);
            app.MatrixField12.Value = newRot(1, 2);
            app.MatrixField13.Value = newRot(1, 3);
            app.MatrixField21.Value = newRot(2, 1);
            app.MatrixField22.Value = newRot(2, 2);
            app.MatrixField23.Value = newRot(2, 3);
            app.MatrixField31.Value = newRot(3, 1);
            app.MatrixField32.Value = newRot(3, 2);
            app.MatrixField33.Value = newRot(3, 3);

            %   Updates the matrix data to the data structure
            updateCoilDataStruct(app);
            %   displays the coil to the coil display
            updateCoilToCoilDisplay(app)
            %   updates the coil's normal and 
            updateCoilToCrossSectionDisplay(app);
        end

        % Value changed function: YAxisRotationEditField
        function YAxisRotationEditFieldValueChanged(app, event)
            value = app.YAxisRotationEditField.Value;

            %   Reset the rotation slider to be centered at 0
            app.YAxisRotationEditField.Value = 0;

            %   getting the rotational matrix for the specified rotation
            %   around the y-axis
            Ry = [cosd(value), 0, -sind(value);
                0, 1, 0;
                sind(value), 0, cosd(value)];  %Y's rotation matrix

            %   turning the edit fields for the rotation in the matrix into a matrix
            CurrentRot = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];  %current rotation matrix


            %   applying the rotation around the y-axis to the current
            %   rotation matrix
            newRot = Ry*CurrentRot;

            %   Updating all of the fields in the matrix
            app.MatrixField11.Value = newRot(1, 1);
            app.MatrixField12.Value = newRot(1, 2);
            app.MatrixField13.Value = newRot(1, 3);
            app.MatrixField21.Value = newRot(2, 1);
            app.MatrixField22.Value = newRot(2, 2);
            app.MatrixField23.Value = newRot(2, 3);
            app.MatrixField31.Value = newRot(3, 1);
            app.MatrixField32.Value = newRot(3, 2);
            app.MatrixField33.Value = newRot(3, 3);

            %   Updates the matrix data to the data structure
            updateCoilDataStruct(app);
            %   displays the coil to the coil display
            updateCoilToCoilDisplay(app)
            %   updates the coil's normal and 
            updateCoilToCrossSectionDisplay(app);
        end

        % Value changed function: ZAxisRotationEditField
        function ZAxisRotationEditFieldValueChanged(app, event)
            value = app.ZAxisRotationEditField.Value;

            %   Reset the rotation slider to be centered at 0
            app.ZAxisRotationEditField.Value = 0;

            %   getting the rotational matrix for the specified rotation
            %   around the z-axis
            Rz = [cosd(value), -sind(value), 0;
                sind(value), cosd(value), 0;
                0, 0, 1];    %Z's rotation matrix

            %   turning the edit fields for the rotation in the matrix into a matrix
            CurrentRot = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];  %current rotation matrix


            %   applying the rotation around the y-axis to the current
            %   rotation matrix
            newRot = Rz*CurrentRot;

            %   Updating all of the fields in the matrix
            app.MatrixField11.Value = newRot(1, 1);
            app.MatrixField12.Value = newRot(1, 2);
            app.MatrixField13.Value = newRot(1, 3);
            app.MatrixField21.Value = newRot(2, 1);
            app.MatrixField22.Value = newRot(2, 2);
            app.MatrixField23.Value = newRot(2, 3);
            app.MatrixField31.Value = newRot(3, 1);
            app.MatrixField32.Value = newRot(3, 2);
            app.MatrixField33.Value = newRot(3, 3);

            %   Updates the matrix data to the data structure
            updateCoilDataStruct(app);
            %   displays the coil to the coil display
            updateCoilToCoilDisplay(app)
            %   updates the coil's normal and 
            updateCoilToCrossSectionDisplay(app);
        end

        % Value changed function: XTranslationEditField
        function XTranslationEditFieldValueChanged(app, event)
            %   Adds the new x-translation to the already existing
            %   x-translation
            value = app.XTranslationEditField.Value;
            app.MatrixField14.Value = app.MatrixField14.Value + value;

            %   Reset the translation slider to be centered at 0
            app.XTranslationEditField.Value = 0;

            %   Updates the matrix data to the data structure
            updateCoilDataStruct(app);
            %   displays the coil to the coil display
            updateCoilToCoilDisplay(app)
            %   updates the coil's normal and 
            updateCoilToCrossSectionDisplay(app);
        end

        % Value changed function: YTranslationEditField
        function YTranslationEditFieldValueChanged(app, event)
            %   Adds the new y-translation to the already existing
            %   y-translation
            value = app.YTranslationEditField.Value;
            app.MatrixField24.Value = app.MatrixField24.Value + value;

            %   Reset the translation slider to be centered at 0
            app.YTranslationEditField.Value = 0;

            %   Updates the matrix data to the data structure
            updateCoilDataStruct(app);
            %   displays the coil to the coil display
            updateCoilToCoilDisplay(app)
            %   updates the coil's normal and 
            updateCoilToCrossSectionDisplay(app);
        end

        % Value changed function: ZTranslationEditField
        function ZTranslationEditFieldValueChanged(app, event)
            %   Adds the new z-translation to the already existing
            %   z-translation
            value = app.ZTranslationEditField.Value;
            app.MatrixField34.Value = app.MatrixField34.Value + value;

            %   Reset the translation slider to be centered at 0
            app.ZTranslationEditField.Value = 0;

            %   Updates the matrix data to the data structure
            updateCoilDataStruct(app);
            %   displays the coil to the coil display
            updateCoilToCoilDisplay(app)
            %   updates the coil's normal and 
            updateCoilToCrossSectionDisplay(app);
        end

        % Clicked callback: PushTool3
        function PushTool3Clicked(app, event)

            %try

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI

            %   Load coil from existing ones
            [file,path] = uigetfile('*.xml', 'Select one .xml file');

            delete(f);

            %   Show Table
            %                 app.LoadedCoilPositionsListBox.Visible = "on";
            %                 app.LoadedCoilPositionsListBoxLabel.Visible = "on";
            %                 app.AnimateButton.Visible = "on";
            %                 app.StopButton.Visible = "on";
            %                 app.AddCoilPositionButton.Visible = "on";
            %                 app.DeleteCoilPositionButton.Visible = "on";
            app.pause = 0;

            %   Read MXL file and store it
            S = readstruct(strcat(path,file));
            %   Get array of structs containing fields including Matrix4D
            Array = S.TriggerMarker;
            %   Create cell arrays
            app.matrices=cell(0);
            app.xmlA = cell(1,length(Array));
            app.positions = cell(1,length(Array));
            for i = 1:length(Array)
                app.xmlA{i} = Array(i).ResponseValues.Value(1).responseAttribute * Array(i).ResponseValues.Value(6).responseAttribute * .01;
                %tfTemp2 = [0 0 1 0; 0 -1 0 0; -1 0 0 0; 0 0 0 1];
                tfTemp2 = [0 0 -1 0; 0 -1 0 0; -1 0 0 0; 0 0 0 1];
                tfTemp2(1, 4) = -(max(app.coil.P(:, 3)) - min(app.coil.P(:, 3)))/2;
                tfTemp1 = [Array(i).Matrix4D.data00Attribute Array(i).Matrix4D.data01Attribute Array(i).Matrix4D.data02Attribute Array(i).Matrix4D.data03Attribute;
                    Array(i).Matrix4D.data10Attribute Array(i).Matrix4D.data11Attribute Array(i).Matrix4D.data12Attribute Array(i).Matrix4D.data13Attribute;
                    Array(i).Matrix4D.data20Attribute Array(i).Matrix4D.data21Attribute Array(i).Matrix4D.data22Attribute Array(i).Matrix4D.data23Attribute;
                    Array(i).Matrix4D.data30Attribute Array(i).Matrix4D.data31Attribute Array(i).Matrix4D.data32Attribute Array(i).Matrix4D.data33Attribute;];
                tfFinal = tfTemp1 * tfTemp2;
                matrix = [tfFinal(1,1) tfFinal(1,2) tfFinal(1,3) tfFinal(1,4);
                    tfFinal(2,1) tfFinal(2,2) tfFinal(2,3) tfFinal(2,4);
                    tfFinal(3,1) tfFinal(3,2) tfFinal(3,3) tfFinal(3,4);
                    tfFinal(4,1) tfFinal(4,2) tfFinal(4,3) tfFinal(4,4)];
                app.matrices{i} = matrix;
                app.positions{i} = strcat('Position', 32, num2str(i));
            end

            app.LoadedCoilPositionsListBox.Items = app.positions;
            app.LoadedCoilPositionsListBox.ItemsData = 1:length(app.positions);
            app.LoadedCoilPositionsListBox.Value = 1;
            event = 0;
            LoadedCoilPositionsListBoxValueChanged(app, event); % update the display and matrix


            %end

        end

        % Value changed function: LoadedCoilPositionsListBox
        function LoadedCoilPositionsListBoxValueChanged(app, event)
            if(~isempty(app.LoadedCoilPositionsListBox.Value))
                value = app.LoadedCoilPositionsListBox.Value;
                matrix = app.matrices{value};
                app.MatrixField11.Value = matrix(1,1);
                app.MatrixField12.Value = matrix(1,2);
                app.MatrixField13.Value = matrix(1,3);
                app.MatrixField14.Value = matrix(1,4);
                app.MatrixField21.Value = matrix(2,1);
                app.MatrixField22.Value = matrix(2,2);
                app.MatrixField23.Value = matrix(2,3);
                app.MatrixField24.Value = matrix(2,4);
                app.MatrixField31.Value = matrix(3,1);
                app.MatrixField32.Value = matrix(3,2);
                app.MatrixField33.Value = matrix(3,3);
                app.MatrixField34.Value = matrix(3,4);
                app.MatrixField41.Value = matrix(4,1);
                app.MatrixField42.Value = matrix(4,2);
                app.MatrixField43.Value = matrix(4,3);
                app.MatrixField44.Value = matrix(4,4);

                %   displays the coil to the coil display
                updateCoilToCoilDisplay(app)
                %   updates the coil's normal and intersection
                updateCoilToCrossSectionDisplay(app);
            end

        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            
            %   Go through each coil position
            for i=1:length(app.LoadedCoilPositionsListBox.Items)
                if app.pause ~= 1
                    app.LoadedCoilPositionsListBox.Value = i;
                    LoadedCoilPositionsListBoxValueChanged(app,event);
                    pause(1);
                else
                    app.pause = 0;
                    break;
                end
            end

        end

        % Value changed function: PlaneSelectionDropDown, 
        % VolumeCoilSelectionDropDown
        function PlaneSelectionDropDownValueChanged(app, event)
            app.processingPlaneidx = app.PlaneSelectionDropDown.Value;
            % update selected processing plane idx

            updatePlanesForPostProcessingTab(app) % update the planes for the CrossSectionPPDisplay

            switch app.solvedplanes{app.processingPlaneidx}.direction
                case 'xy'
                    view(app.CoilPPDisplay, 0, 90);
                case 'xz'
                    view(app.CoilPPDisplay, 0, 0);
                case 'yz'
                    view(app.CoilPPDisplay, 90, 0);
            end

            % run function to redraw the new selected plane
            plotEField(app);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.pause = 1;
        end

        % Button pushed function: DeleteCoilPositionButton
        function DeleteCoilPositionButtonPushed(app, event)
            if(~isempty(app.LoadedCoilPositionsListBox.Items))
                if (length(app.LoadedCoilPositionsListBox.Items) > 1)
                value = app.LoadedCoilPositionsListBox.Value;
                app.matrices(value) = [];
                % app.xmlA(nvalue) = [];
                app.positions(end) = [];
                app.LoadedCoilPositionsListBox.Items = app.positions;
                app.LoadedCoilPositionsListBox.ItemsData(end) = [];
                if (~isempty(app.LoadedCoilPositionsListBox.ItemsData))
                    app.LoadedCoilPositionsListBox.Value = app.LoadedCoilPositionsListBox.ItemsData(end);
                end
                LoadedCoilPositionsListBoxValueChanged(app, event);
                else
                    throwErrorPopup(app, 'You aren''t able to delete your last coil position!', '');
                end
            end
        end

        % Button pushed function: AddCoilPositionButton
        function AddCoilPositionButtonPushed(app, event)
            app.positions{end+1} = strcat('Position', 32, num2str(length(app.positions)+1));
            % app.xmlA{end+1} = 0; % there is no value so put average current for the coil?

            matrix = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value, app.MatrixField14.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value, app.MatrixField24.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value, app.MatrixField34.Value;
                app.MatrixField41.Value, app.MatrixField42.Value, app.MatrixField43.Value, app.MatrixField44.Value];
            
            app.matrices{end+1} = matrix;
            app.LoadedCoilPositionsListBox.Items = app.positions;
            app.LoadedCoilPositionsListBox.ItemsData = 1:length(app.positions);
            app.LoadedCoilPositionsListBox.Value = app.LoadedCoilPositionsListBox.ItemsData(end);
        end

        % Value changed function: ScaleSurfaceSlider
        function ScaleSurfaceSliderValueChanged(app, event)
            value = app.ScaleSurfaceSlider.Value;
            
            app.SurfaceDisplay.XLim = [-value,value];
            app.SurfaceDisplay.YLim = [-value,value];
            app.SurfaceDisplay.ZLim = [-value,value];
        end

        % Callback function: FieldSourceTypeDropDown, 
        % FieldValueTypeDropDown, RedrawSurfacePlotButton, 
        % SurfaceCoilSelectionDropDown, SurfaceEFieldLocationSwitch, 
        % SurfaceHeadCompartmentsDropDown
        function SurfaceHeadCompartmentsDropDownValueChanged(app, event)
            app.LampSurfacePlot.Color = [1,1,0];
            pause(1);
            %Update the surface display
            updateSurfaceDisplay(app);

            app.LampSurfacePlot.Color = [0,1,0];
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            app.LampExporting.Color = [1,1,0];
            pause(1);

            % Gets the user's requested location for the data
            try
                filepath = uigetdir();
            catch
                app.LampExporting.Color = [0,1,0];
                return
            end

            % Exports all of the requested data as dictated by app.DataExportTree
            exportData(app, filepath);

            app.LampExporting.Color = [0,1,0];
        end

        % Value changed function: CoilAxisRotationEditField
        function CoilAxisRotationEditFieldValueChanged(app, event)
            value = app.CoilAxisRotationEditField.Value;

            %   Reset the edit field to be centered at 0
            app.CoilAxisRotationEditField.Value = 0;

            %   getting the rotational matrix for the specified rotation
            %   around the z-axis
            Rz = [cosd(value), -sind(value), 0;
                sind(value), cosd(value), 0;
                0, 0, 1];    %Z's rotation matrix

            %   turning the edit fields for the rotation in the matrix into a matrix
            CurrentRot = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];  %current rotation matrix


            %   applying the rotation around the y-axis to the current
            %   rotation matrix
            newRot = CurrentRot*Rz;

            %   Updating all of the fields in the matrix
            app.MatrixField11.Value = newRot(1, 1);
            app.MatrixField12.Value = newRot(1, 2);
            app.MatrixField13.Value = newRot(1, 3);
            app.MatrixField21.Value = newRot(2, 1);
            app.MatrixField22.Value = newRot(2, 2);
            app.MatrixField23.Value = newRot(2, 3);
            app.MatrixField31.Value = newRot(3, 1);
            app.MatrixField32.Value = newRot(3, 2);
            app.MatrixField33.Value = newRot(3, 3);

            %   Updates the matrix data to the data structure
            updateCoilDataStruct(app);
            %   displays the coil to the coil display
            updateCoilToCoilDisplay(app)
            %   updates coil info to crosssectiondisplay
            updateCoilToCrossSectionDisplay(app);

        end

        % Button pushed function: LoadDipoleCoilButton
        function LoadDipoleCoilButtonPushed(app, event)
            %try
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI

                %   Load coil from existing ones
                [file,path] = uigetfile('*.ccd', 'Select one .m file from Coil Folder', [ app.dir app.slash 'Coil' ]);

                delete(f);

                %   Update table with file name
                app.UITable3.Data = {file};

                [app.strcoil app.coilCAD.positions] = ImportDipoleCoil(file, [app.dir app.slash 'Coil' app.slash]);

                app.isDipoleCoil = true;
            %end
        end

        % Button pushed function: SelectSTLsButton
        function SelectSTLsButtonPushed(app, event)
            try
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI

                %   Save file and path of the opened documents
                [file,path] = uigetfile('*.stl', 'Select one or more stl files', [ app.dir app.slash 'Model' app.slash ], 'MultiSelect', 'on');

                delete(f); %delete the dummy figure

                n_files = numel(cellstr(file));

                %   Initialize cell arrays
                app.meshConductivities = cell(1,n_files);
                app.meshPath = cell(1,n_files);
                app.meshInternalNames = cell(1,n_files);
                app.meshList = cell(1,n_files);
                app.meshEncTissues = cell(1,n_files);
                app.meshFileNames = cell(1,n_files);

                %   Change values in the table
                for k = 1:n_files
                    if n_files==1
                        fileOut = file;
                    else
                        fileOut = file{k};
                    end

                    %  Parse common tissue name from filename
                    %  Expression is the list of tissue names that the function will
                    %  search for in the filename.
                    %  More names can be added following the same format
                    expression = 'bone|skull|csf|eyes|gm|skin|ventricles|wm|cerebellum';

                    %  regexpi: Case-insensitive regex search for a tissue name in fileOut.
                    %  lower: forces lowercase output for consistency
                    %  regFileOut: Cell array with the tissue name from fileOut
                    regFileOut = lower(regexpi(fileOut,expression,'match'));

                    %  regFileChar: Char array for use in switch statement
                    regFileChar = char(regFileOut);

                    %  Switch statement takes regFileChar and assigns default
                    %  name, conductivity, and enclosing tissue
                    %  This list of cases can be expanded to accomodate more
                    %  tissue names
                    switch regFileChar
                        case 'bone', nameFile{k} = 'Skull'; conductivity{k} = 0.010; enclosingTissue{k} = 'Skin';  %  \/
                        case 'skull', nameFile{k} = 'Skull'; conductivity{k} = 0.010; enclosingTissue{k} = 'Skin';  %   /\ Treating bone and skull the same
                        case 'csf', nameFile{k} = 'CSF'; conductivity{k} = 1.654 ; enclosingTissue{k} = 'Skull';
                        case 'eyes', nameFile{k} = 'Eyes'; conductivity{k} = 1.654; enclosingTissue{k} = 'WM';  %  Default conductivity might not be right!!
                        case 'gm', nameFile{k} = 'GM'; conductivity{k} = 0.2750; enclosingTissue{k} = 'CSF';
                        case 'skin', nameFile{k} = 'Skin'; conductivity{k} = 0.4650; enclosingTissue{k} = 'FreeSpace';
                        case 'ventricles', nameFile{k} = 'Ventricles'; conductivity{k} = 1.654; enclosingTissue{k} = 'WM';
                        case 'wm', nameFile{k} = 'WM'; conductivity{k} = 0.1260; enclosingTissue{k} = 'GM';
                        case 'cerebellum', nameFile{k} = 'Cerebellum'; conductivity{k} = 0.1260; enclosingTissue{k} = 'CSF';
                        otherwise, nameFile{k} = '[EnterName]'; conductivity{k} = '[EnterConductivity]'; enclosingTissue{k} = '[EnterEnclosingTissue]';
                    end

                    if k == 1
                        app.UITable.Data = {int2str(k) path fileOut nameFile{k} conductivity{k} enclosingTissue{k}};
                    else
                        app.UITable.Data = [app.UITable.Data; {int2str(k) path fileOut nameFile{k} conductivity{k} enclosingTissue{k}}];
                    end
                end
            end
        end

        % Button pushed function: ProceedafterSelectionButton
        function ProceedafterSelectionButtonPushed(app, event)
            prevCd = cd;
            cd(app.dir);
            % Turn status lamp color to orange
            app.ImportStatusLamp.Color = [1,1,0];
            pause(0.5);


            %   Save data from the table in cell arrays
            for i = 1:size(app.UITable.Data,1)

                app.meshPath{i} = cell2mat(app.UITable.Data(i,2));

                addpath(app.meshPath{i}); % add stl files to path

                app.meshFileNames{i} = cell2mat(app.UITable.Data(i,3));
                app.meshList{i} = stlread(cell2mat(app.UITable.Data(i,3)));
                app.meshInternalNames{i} = cell2mat(app.UITable.Data(i,4));
                app.meshConductivities{i} = cell2mat(app.UITable.Data(i,5));
                app.meshEncTissues{i} = cell2mat(app.UITable.Data(i,6));

                % generate mat files for the mesh layers
                NewName =  strcat(app.meshFileNames{i}(1:end-4), '.mat');
                stl_to_mat_GUI([app.meshPath{i}, app.slash, NewName], [app.meshPath{i}, app.slash, app.meshFileNames{i}]);
            end


            %   Sets up resources for the brain model
%             createTxt(app);
            app.mesh = modelProcessor_app(app);

            app.WindowTabGroup.SelectedTab = app.WindowTabGroup.Children(2);    % Sets the current tab to the second tab in the tab group (Planning tab)

            %   Sets values to conform to the imported data
            postImportSetup(app);

            %   Displays the initial image and creates correct coil
            %   data struct
            event = 0; % fake event

            app.LoadedCoilPositionsListBox.Items = {};
            app.positions = {};
            app.LoadedCoilPositionsListBox.ItemsData = [];
            app.matrices = [];

            ResetTranslationButtonPushed(app, event); % returns coil to default location
            ResetRotationButtonPushed(app, event); % returns coil to default rotation
            AddCoilPositionButtonPushed(app, event); % adds default coil position
            updateImage(app); % displays the image

            % Change status lamp color to green
            app.ImportStatusLamp.Color = [0,1,0];
            cd(prevCd);
        end

        % Clicked callback: PushTool4
        function PushTool4Clicked(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI

            %   Select Brainsway Coil Data
            [file,path] = uigetfile('*.xlsx', 'Select Brainsway Data', app.dir);

            delete(f);
            addpath(path);
            matrices = loadBrainsightData(file); % loads the matricies from the file into app.matricies

            if (length(matrices)>0)
                app.matrices = matrices;
                app.positions = cell(0);

                for j=1:length(matrices)
                    app.positions{j} = strcat('Position', 32, num2str(j));
                end
                app.LoadedCoilPositionsListBox.Items = app.positions;
                app.LoadedCoilPositionsListBox.ItemsData = 1:length(app.positions);
                app.LoadedCoilPositionsListBox.Value = app.LoadedCoilPositionsListBox.ItemsData(end);
                event = 0;
                LoadedCoilPositionsListBoxValueChanged(app, event);
            else
                throwErrorPopup(app, 'No Coil Positions Identified', 'Error');
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Save current session'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'C:\Users\Alton\Documents\MATLAB\BEM-FMM-GUI\GUIEngine\icon.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Load saved session'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'C:\Users\Alton\Documents\MATLAB\BEM-FMM-GUI\GUIEngine\icon2.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Load XML file containing transformation matrices'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = 'C:\Users\Alton\Documents\MATLAB\BEM-FMM-GUI\GUIEngine\icon3.png';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.WindowTabGroup = uitabgroup(app.GridLayout);
            app.ImportingTab = uitab(app.WindowTabGroup);
            app.ImportingTab.Title = 'Importing';
            app.GridLayout3 = uigridlayout(app.ImportingTab);
            app.LoadCoilButton = uibutton(app.GridLayout3, 'push');
            app.LoadCoilButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCoilButtonPushed, true);
            app.LoadCoilButton.Text = 'Load Coil';
            app.LoadNiftiDataButton = uibutton(app.GridLayout3, 'push');
            app.LoadNiftiDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNiftiDataButtonPushed, true);
            app.LoadNiftiDataButton.Text = 'Load Nifti Data';
            app.UITable2 = uitable(app.GridLayout3);
            app.UITable2.ColumnName = {'File Name'};
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.GridLayout3);
            app.UITable3.ColumnName = {'File Name'};
            app.UITable3.RowName = {};
            app.ImportStatusLamp = uilamp(app.GridLayout3);
            app.LoadPreviousSessionButton = uibutton(app.GridLayout3, 'push');
            app.LoadPreviousSessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPreviousSessionButtonPushed, true);
            app.LoadPreviousSessionButton.Enable = 'off';
            app.LoadPreviousSessionButton.Visible = 'off';
            app.LoadPreviousSessionButton.Text = 'Load Previous Session';
            app.LoadDipoleCoilButton = uibutton(app.GridLayout3, 'push');
            app.LoadDipoleCoilButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDipoleCoilButtonPushed, true);
            app.LoadDipoleCoilButton.Text = 'Load Dipole Coil';
            app.SelectSTLsButton = uibutton(app.GridLayout3, 'push');
            app.SelectSTLsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSTLsButtonPushed, true);
            app.SelectSTLsButton.Text = 'Select STLs';
            app.ProceedafterSelectionButton = uibutton(app.GridLayout3, 'push');
            app.ProceedafterSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedafterSelectionButtonPushed, true);
            app.ProceedafterSelectionButton.Text = 'Proceed after Selection';
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Item #'; 'File Path'; 'File Name'; 'Internal Name'; 'Conductivities (S/m)'; 'Enclosing Tissue'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.PlanningTab = uitab(app.WindowTabGroup);
            app.PlanningTab.Title = 'Planning';
            app.GridLayout2 = uigridlayout(app.PlanningTab);
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.CoilDisplay = uiaxes(app.GridLayout8);
            xlabel(app.CoilDisplay, 'X (mm)')
            ylabel(app.CoilDisplay, 'Y (mm)')
            zlabel(app.CoilDisplay, 'Z (mm)')
            app.CrossSectionDisplay = uiaxes(app.GridLayout8);
            xlabel(app.CrossSectionDisplay, 'X (mm)')
            ylabel(app.CrossSectionDisplay, 'Y (mm)')
            zlabel(app.CrossSectionDisplay, 'Z (mm)')
            app.TabGroup = uitabgroup(app.GridLayout8);
            app.PlanesTab = uitab(app.TabGroup);
            app.PlanesTab.Title = 'Planes';
            app.GridLayout32 = uigridlayout(app.PlanesTab);
            app.GridLayout33 = uigridlayout(app.GridLayout32);
            app.PlanesDropDownLabel = uilabel(app.GridLayout33);
            app.PlanesDropDownLabel.Text = 'Planes';
            app.PlanesDropDown = uidropdown(app.GridLayout33);
            app.PlanesDropDown.Items = {};
            app.PlanesDropDown.ValueChangedFcn = createCallbackFcn(app, @PlanesDropDownValueChanged, true);
            app.PlanesDropDown.Value = {};
            app.PlaneNameEditFieldLabel = uilabel(app.GridLayout33);
            app.PlaneNameEditFieldLabel.Text = 'Plane Name';
            app.PlaneNameEditField = uieditfield(app.GridLayout33, 'text');
            app.PlaneNameEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordcmEditFieldValueChanged, true);
            app.DirectionDropDownLabel = uilabel(app.GridLayout33);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.GridLayout33);
            app.DirectionDropDown.Items = {'xy', 'xz', 'yz'};
            app.DirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @XCoordcmEditFieldValueChanged, true);
            app.DirectionDropDown.Value = 'xy';
            app.GridLayout34 = uigridlayout(app.GridLayout32);
            app.GridLayout35 = uigridlayout(app.GridLayout34);
            app.YCoordLabel = uilabel(app.GridLayout35);
            app.YCoordLabel.Text = 'YCoord';
            app.YCoordEditField = uieditfield(app.GridLayout35, 'numeric');
            app.YCoordEditField.ValueDisplayFormat = '%11.4g mm';
            app.YCoordEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordcmEditFieldValueChanged, true);
            app.ZCoordLabel = uilabel(app.GridLayout35);
            app.ZCoordLabel.Text = 'ZCoord';
            app.ZCoordEditField = uieditfield(app.GridLayout35, 'numeric');
            app.ZCoordEditField.ValueDisplayFormat = '%11.4g mm';
            app.ZCoordEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordcmEditFieldValueChanged, true);
            app.XCoordLabel = uilabel(app.GridLayout35);
            app.XCoordLabel.Text = 'XCoord';
            app.XCoordEditField = uieditfield(app.GridLayout35, 'numeric');
            app.XCoordEditField.ValueDisplayFormat = '%11.4g mm';
            app.XCoordEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordcmEditFieldValueChanged, true);
            app.GridLayout38 = uigridlayout(app.GridLayout34);
            app.GridLayout39 = uigridlayout(app.GridLayout38);
            app.PlaneWidthmmLabel = uilabel(app.GridLayout39);
            app.PlaneWidthmmLabel.Text = 'Plane Width';
            app.PlaneWidthEditField = uieditfield(app.GridLayout39, 'numeric');
            app.PlaneWidthEditField.ValueDisplayFormat = '%11.4g mm';
            app.PlaneWidthEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordcmEditFieldValueChanged, true);
            app.GridLayout40 = uigridlayout(app.GridLayout38);
            app.AddNewPlaneButton = uibutton(app.GridLayout40, 'push');
            app.AddNewPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewPlaneButtonPushed, true);
            app.AddNewPlaneButton.Text = 'Add New Plane';
            app.GridLayout41 = uigridlayout(app.GridLayout38);
            app.DeletePlaneButton = uibutton(app.GridLayout41, 'push');
            app.DeletePlaneButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePlaneButtonPushed, true);
            app.DeletePlaneButton.Text = 'Delete Plane';
            app.GridLayout36 = uigridlayout(app.GridLayout34);
            app.CoilDisplayVisibilitySwitchLabel = uilabel(app.GridLayout36);
            app.CoilDisplayVisibilitySwitchLabel.Text = 'Coil Display Visibility';
            app.CoilDisplayVisibilitySwitch = uiswitch(app.GridLayout36, 'slider');
            app.CoilDisplayVisibilitySwitch.ItemsData = [false true];
            app.CoilDisplayVisibilitySwitch.ValueChangedFcn = createCallbackFcn(app, @CoilDisplayVisibilitySwitchValueChanged, true);
            app.CoilDisplayVisibilitySwitch.Value = true;
            app.GridLayout37 = uigridlayout(app.GridLayout36);
            app.CenterOnPointButton = uibutton(app.GridLayout37, 'push');
            app.CenterOnPointButton.ButtonPushedFcn = createCallbackFcn(app, @CenterOnPointButtonPushed, true);
            app.CenterOnPointButton.Text = 'Center On Point';
            app.LinesTab = uitab(app.TabGroup);
            app.LinesTab.Title = 'Lines';
            app.GridLayout42 = uigridlayout(app.LinesTab);
            app.GridLayout43 = uigridlayout(app.GridLayout42);
            app.LinesDropDownLabel = uilabel(app.GridLayout43);
            app.LinesDropDownLabel.Text = 'Lines';
            app.LinesDropDown = uidropdown(app.GridLayout43);
            app.LinesDropDown.Items = {};
            app.LinesDropDown.ValueChangedFcn = createCallbackFcn(app, @LinesDropDownValueChanged, true);
            app.LinesDropDown.Value = {};
            app.LineNameEditFieldLabel = uilabel(app.GridLayout43);
            app.LineNameEditFieldLabel.Text = 'Line Name';
            app.LineNameEditField = uieditfield(app.GridLayout43, 'text');
            app.LineNameEditField.ValueChangedFcn = createCallbackFcn(app, @LineNameEditFieldValueChanged, true);
            app.LineLengthLabel = uilabel(app.GridLayout43);
            app.LineLengthLabel.Text = 'Line Length';
            app.LineLengthEditField = uieditfield(app.GridLayout43, 'numeric');
            app.LineLengthEditField.ValueDisplayFormat = '%11.4g mm';
            app.LineLengthEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordLinecmEditFieldValueChanged, true);
            app.LineLengthEditField.Value = 100;
            app.GridLayout44 = uigridlayout(app.GridLayout42);
            app.GridLayout45 = uigridlayout(app.GridLayout44);
            app.XCoordmmLabel = uilabel(app.GridLayout45);
            app.XCoordmmLabel.Text = 'XCoord';
            app.XCoordLineEditField = uieditfield(app.GridLayout45, 'numeric');
            app.XCoordLineEditField.ValueDisplayFormat = '%11.4g mm';
            app.XCoordLineEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordLinecmEditFieldValueChanged, true);
            app.YCoordmmLabel = uilabel(app.GridLayout45);
            app.YCoordmmLabel.Text = 'YCoord';
            app.YCoordLineEditField = uieditfield(app.GridLayout45, 'numeric');
            app.YCoordLineEditField.ValueDisplayFormat = '%11.4g mm';
            app.YCoordLineEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordLinecmEditFieldValueChanged, true);
            app.ZCoordmmLabel = uilabel(app.GridLayout45);
            app.ZCoordmmLabel.Text = 'ZCoord';
            app.ZCoordLineEditField = uieditfield(app.GridLayout45, 'numeric');
            app.ZCoordLineEditField.ValueDisplayFormat = '%11.4g mm';
            app.ZCoordLineEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordLinecmEditFieldValueChanged, true);
            app.GridLayout46 = uigridlayout(app.GridLayout44);
            app.XVectorDirectionEditFieldLabel = uilabel(app.GridLayout46);
            app.XVectorDirectionEditFieldLabel.Text = 'X Vector Direction';
            app.XVectorDirectionEditField = uieditfield(app.GridLayout46, 'numeric');
            app.XVectorDirectionEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordLinecmEditFieldValueChanged, true);
            app.YVectorDirectionEditFieldLabel = uilabel(app.GridLayout46);
            app.YVectorDirectionEditFieldLabel.Text = 'Y Vector Direction';
            app.YVectorDirectionEditField = uieditfield(app.GridLayout46, 'numeric');
            app.YVectorDirectionEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordLinecmEditFieldValueChanged, true);
            app.ZVectorDirectionEditFieldLabel = uilabel(app.GridLayout46);
            app.ZVectorDirectionEditFieldLabel.Text = 'Z Vector Direction';
            app.ZVectorDirectionEditField = uieditfield(app.GridLayout46, 'numeric');
            app.ZVectorDirectionEditField.ValueChangedFcn = createCallbackFcn(app, @CoilDisplayLineVisibilitySwitchValueChanged, true);
            app.GridLayout47 = uigridlayout(app.GridLayout44);
            app.GridLayout48 = uigridlayout(app.GridLayout47);
            app.AddNewLineButton = uibutton(app.GridLayout48, 'push');
            app.AddNewLineButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewLineButtonPushed, true);
            app.AddNewLineButton.Text = 'Add New Line';
            app.DeleteLineButton = uibutton(app.GridLayout48, 'push');
            app.DeleteLineButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLineButtonPushed, true);
            app.DeleteLineButton.Text = 'Delete Line';
            app.GridLayout49 = uigridlayout(app.GridLayout47);
            app.CoilDisplayVisibilitySwitch_2Label = uilabel(app.GridLayout49);
            app.CoilDisplayVisibilitySwitch_2Label.Text = 'Coil Display Visibility';
            app.CoilDisplayLineVisibilitySwitch = uiswitch(app.GridLayout49, 'slider');
            app.CoilDisplayLineVisibilitySwitch.ItemsData = [false true];
            app.CoilDisplayLineVisibilitySwitch.ValueChangedFcn = createCallbackFcn(app, @XCoordLinecmEditFieldValueChanged, true);
            app.CoilDisplayLineVisibilitySwitch.Value = true;
            app.CenterLineOnPointButton = uibutton(app.GridLayout49, 'push');
            app.CenterLineOnPointButton.ButtonPushedFcn = createCallbackFcn(app, @CenterLineOnPointButtonPushed, true);
            app.CenterLineOnPointButton.Text = 'Center On Point';
            app.CoilControlsTabGroup = uitabgroup(app.GridLayout8);
            app.AutomaticTab = uitab(app.CoilControlsTabGroup);
            app.AutomaticTab.Title = 'Automatic';
            app.GridLayout16 = uigridlayout(app.AutomaticTab);
            app.GridLayout17 = uigridlayout(app.GridLayout16);
            app.ChosenTargetLabel = uilabel(app.GridLayout17);
            app.ChosenTargetLabel.Text = 'Chosen Target';
            app.GridLayout18 = uigridlayout(app.GridLayout17);
            app.PointXValEditField = uieditfield(app.GridLayout18, 'numeric');
            app.PointXValEditField.ValueDisplayFormat = '%11.4g mm';
            app.PointXValEditField.ValueChangedFcn = createCallbackFcn(app, @PointXValEditFieldValueChanged, true);
            app.PointXValEditField.Tooltip = {'The targeted point''s x-coordinate'};
            app.PointYValEditField = uieditfield(app.GridLayout18, 'numeric');
            app.PointYValEditField.ValueDisplayFormat = '%11.4g mm';
            app.PointYValEditField.ValueChangedFcn = createCallbackFcn(app, @PointXValEditFieldValueChanged, true);
            app.PointYValEditField.Tooltip = {'The targeted point''s y-coordinate'};
            app.PointZValEditField = uieditfield(app.GridLayout18, 'numeric');
            app.PointZValEditField.ValueDisplayFormat = '%11.4g mm';
            app.PointZValEditField.ValueChangedFcn = createCallbackFcn(app, @PointXValEditFieldValueChanged, true);
            app.PointZValEditField.Tooltip = {'The targeted point''s z-coordinate'};
            app.GridLayout19 = uigridlayout(app.GridLayout17);
            app.UpdateTargetButton = uibutton(app.GridLayout19, 'push');
            app.UpdateTargetButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTargetButtonPushed, true);
            app.UpdateTargetButton.Tooltip = {'Overwrites the point in the program. Only do this for point on the GM layer'};
            app.UpdateTargetButton.Text = 'Update Target';
            app.RemovePointsButton = uibutton(app.GridLayout19, 'push');
            app.RemovePointsButton.ButtonPushedFcn = createCallbackFcn(app, @RemovePointsButtonPushed, true);
            app.RemovePointsButton.Tooltip = {'Overwrites the point in the program. Only do this for point on the GM layer'};
            app.RemovePointsButton.Text = 'Remove Points';
            app.GridLayout20 = uigridlayout(app.GridLayout17);
            app.DistanceFromSkinLabel = uilabel(app.GridLayout20);
            app.DistanceFromSkinLabel.Text = 'Distance From Skin';
            app.CoilSkinDistanceEditField = uieditfield(app.GridLayout20, 'numeric');
            app.CoilSkinDistanceEditField.ValueDisplayFormat = '%11.4g mm';
            app.GridLayout21 = uigridlayout(app.GridLayout17);
            app.UpdateDisplayButton = uibutton(app.GridLayout21, 'push');
            app.UpdateDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDisplayButtonPushed, true);
            app.UpdateDisplayButton.Tooltip = {'Updates the display to reflect the point and the radius values'};
            app.UpdateDisplayButton.Text = 'Update Display';
            app.GridLayout22 = uigridlayout(app.GridLayout16);
            app.GridLayout23 = uigridlayout(app.GridLayout22);
            app.CoilAxisRotationEditFieldLabel = uilabel(app.GridLayout23);
            app.CoilAxisRotationEditFieldLabel.Text = 'Coil-Axis Rotation';
            app.CoilAxisRotationEditField = uieditfield(app.GridLayout23, 'numeric');
            app.CoilAxisRotationEditField.ValueDisplayFormat = '%11.4g degrees';
            app.CoilAxisRotationEditField.ValueChangedFcn = createCallbackFcn(app, @CoilAxisRotationEditFieldValueChanged, true);
            app.GridLayout26 = uigridlayout(app.GridLayout22);
            app.ResetTranslationButton_3 = uibutton(app.GridLayout26, 'push');
            app.ResetTranslationButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetTranslationButtonPushed, true);
            app.ResetTranslationButton_3.Tooltip = {'Resets the translation of the coil'};
            app.ResetTranslationButton_3.Text = 'Reset Translation';
            app.ResetRotationButton_3 = uibutton(app.GridLayout26, 'push');
            app.ResetRotationButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetRotationButtonPushed, true);
            app.ResetRotationButton_3.Tooltip = {'Resets the rotation of the coil'};
            app.ResetRotationButton_3.Text = 'Reset Rotation';
            app.GridLayout51 = uigridlayout(app.GridLayout22);
            app.AddObservationPlanesButton = uibutton(app.GridLayout51, 'push');
            app.AddObservationPlanesButton.ButtonPushedFcn = createCallbackFcn(app, @AddObservationPlanesButtonPushed, true);
            app.AddObservationPlanesButton.Text = 'Add Observation Planes';
            app.AddObservationLineButton = uibutton(app.GridLayout51, 'push');
            app.AddObservationLineButton.ButtonPushedFcn = createCallbackFcn(app, @AddObservationLineButtonPushed, true);
            app.AddObservationLineButton.Text = 'Add Observation Line';
            app.ManualTab = uitab(app.CoilControlsTabGroup);
            app.ManualTab.Title = 'Manual';
            app.GridLayout27 = uigridlayout(app.ManualTab);
            app.GridLayout28 = uigridlayout(app.GridLayout27);
            app.ResetTranslationButton_4 = uibutton(app.GridLayout28, 'push');
            app.ResetTranslationButton_4.ButtonPushedFcn = createCallbackFcn(app, @ResetTranslationButtonPushed, true);
            app.ResetTranslationButton_4.Tooltip = {'Resets the translation of the coil'};
            app.ResetTranslationButton_4.Text = 'Reset Translation';
            app.GridLayout29 = uigridlayout(app.GridLayout27);
            app.ResetRotationButton_4 = uibutton(app.GridLayout29, 'push');
            app.ResetRotationButton_4.ButtonPushedFcn = createCallbackFcn(app, @ResetRotationButtonPushed, true);
            app.ResetRotationButton_4.Tooltip = {'Resets the rotation of the coil'};
            app.ResetRotationButton_4.Text = 'Reset Rotation';
            app.GridLayout30 = uigridlayout(app.GridLayout27);
            app.XTranslationLabel = uilabel(app.GridLayout30);
            app.XTranslationLabel.Text = 'X Translation';
            app.XTranslationEditField = uieditfield(app.GridLayout30, 'numeric');
            app.XTranslationEditField.ValueDisplayFormat = '%11.4g mm';
            app.XTranslationEditField.ValueChangedFcn = createCallbackFcn(app, @XTranslationEditFieldValueChanged, true);
            app.YTranslationLabel = uilabel(app.GridLayout30);
            app.YTranslationLabel.Text = 'Y Translation';
            app.YTranslationEditField = uieditfield(app.GridLayout30, 'numeric');
            app.YTranslationEditField.ValueDisplayFormat = '%11.4g mm';
            app.YTranslationEditField.ValueChangedFcn = createCallbackFcn(app, @YTranslationEditFieldValueChanged, true);
            app.ZTranslationLabel = uilabel(app.GridLayout30);
            app.ZTranslationLabel.Text = 'Z Translation';
            app.ZTranslationEditField = uieditfield(app.GridLayout30, 'numeric');
            app.ZTranslationEditField.ValueDisplayFormat = '%11.4g mm';
            app.ZTranslationEditField.ValueChangedFcn = createCallbackFcn(app, @ZTranslationEditFieldValueChanged, true);
            app.GridLayout31 = uigridlayout(app.GridLayout27);
            app.XAxisRotationLabel = uilabel(app.GridLayout31);
            app.XAxisRotationLabel.Text = 'X-Axis Rotation';
            app.XAxisRotationEditField = uieditfield(app.GridLayout31, 'numeric');
            app.XAxisRotationEditField.ValueDisplayFormat = '%11.4g degrees';
            app.XAxisRotationEditField.ValueChangedFcn = createCallbackFcn(app, @XAxisRotationEditFieldValueChanged, true);
            app.YAxisRotationpiradsLabel_3 = uilabel(app.GridLayout31);
            app.YAxisRotationpiradsLabel_3.Text = 'Y-Axis Rotation';
            app.YAxisRotationEditField = uieditfield(app.GridLayout31, 'numeric');
            app.YAxisRotationEditField.ValueDisplayFormat = '%11.4g degrees';
            app.YAxisRotationEditField.ValueChangedFcn = createCallbackFcn(app, @YAxisRotationEditFieldValueChanged, true);
            app.YAxisRotationpiradsLabel_4 = uilabel(app.GridLayout31);
            app.YAxisRotationpiradsLabel_4.Text = 'Z-Axis Rotation';
            app.ZAxisRotationEditField = uieditfield(app.GridLayout31, 'numeric');
            app.ZAxisRotationEditField.ValueDisplayFormat = '%11.4g degrees';
            app.ZAxisRotationEditField.ValueChangedFcn = createCallbackFcn(app, @ZAxisRotationEditFieldValueChanged, true);
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.ContributorsAltonMilesGrahamOrnsteinKseniiaRomanovaPanel = uipanel(app.GridLayout9);
            app.ContributorsAltonMilesGrahamOrnsteinKseniiaRomanovaPanel.Title = 'Contributors: Alton Miles, Graham Ornstein, Kseniia Romanova';
            app.GridLayout10 = uigridlayout(app.ContributorsAltonMilesGrahamOrnsteinKseniiaRomanovaPanel);
            app.Versionv051alphaLabel = uilabel(app.GridLayout10);
            app.Versionv051alphaLabel.Text = 'Version v0.5.1-alpha';
            app.TransformationMatrixVisabilityLabel = uilabel(app.GridLayout9);
            app.TransformationMatrixVisabilityLabel.Text = 'Transformation Matrix Visability';
            app.TransformationMatrixSwitch = uiswitch(app.GridLayout9, 'slider');
            app.TransformationMatrixSwitch.ValueChangedFcn = createCallbackFcn(app, @TransformationMatrixSwitchValueChanged, true);
            app.TransformationMatrixSwitch.Value = 'On';
            app.CoilTransformationMatrixPanel = uipanel(app.GridLayout9);
            app.CoilTransformationMatrixPanel.Title = 'Coil Transformation Matrix';
            app.GridLayout11 = uigridlayout(app.CoilTransformationMatrixPanel);
            app.MatrixField21 = uieditfield(app.GridLayout11, 'numeric');
            app.MatrixField21.ValueDisplayFormat = '%8.4g';
            app.MatrixField21.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField31 = uieditfield(app.GridLayout11, 'numeric');
            app.MatrixField31.ValueDisplayFormat = '%8.4g';
            app.MatrixField31.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField41 = uieditfield(app.GridLayout11, 'numeric');
            app.MatrixField41.ValueDisplayFormat = '%8.4g';
            app.MatrixField41.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField41.Editable = 'off';
            app.MatrixField42 = uieditfield(app.GridLayout11, 'numeric');
            app.MatrixField42.ValueDisplayFormat = '%8.4g';
            app.MatrixField42.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField42.Editable = 'off';
            app.MatrixField11 = uieditfield(app.GridLayout11, 'numeric');
            app.MatrixField11.ValueDisplayFormat = '%8.4g';
            app.MatrixField11.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField11.Value = 1;
            app.MatrixField13 = uieditfield(app.GridLayout11, 'numeric');
            app.MatrixField13.ValueDisplayFormat = '%8.4g';
            app.MatrixField13.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField32 = uieditfield(app.GridLayout11, 'numeric');
            app.MatrixField32.ValueDisplayFormat = '%8.4g';
            app.MatrixField32.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField22 = uieditfield(app.GridLayout11, 'numeric');
            app.MatrixField22.ValueDisplayFormat = '%8.4g';
            app.MatrixField22.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField22.Value = 1;
            app.MatrixField12 = uieditfield(app.GridLayout11, 'numeric');
            app.MatrixField12.ValueDisplayFormat = '%8.4g';
            app.MatrixField12.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField14 = uieditfield(app.GridLayout11, 'numeric');
            app.MatrixField14.ValueDisplayFormat = '%8.4g';
            app.MatrixField14.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField23 = uieditfield(app.GridLayout11, 'numeric');
            app.MatrixField23.ValueDisplayFormat = '%8.4g';
            app.MatrixField23.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField43 = uieditfield(app.GridLayout11, 'numeric');
            app.MatrixField43.ValueDisplayFormat = '%8.4g';
            app.MatrixField43.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField43.Editable = 'off';
            app.MatrixField24 = uieditfield(app.GridLayout11, 'numeric');
            app.MatrixField24.ValueDisplayFormat = '%8.4g';
            app.MatrixField24.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField34 = uieditfield(app.GridLayout11, 'numeric');
            app.MatrixField34.ValueDisplayFormat = '%8.4g';
            app.MatrixField34.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField34.Value = 80;
            app.MatrixField33 = uieditfield(app.GridLayout11, 'numeric');
            app.MatrixField33.ValueDisplayFormat = '%8.4g';
            app.MatrixField33.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField33.Value = 1;
            app.MatrixField44 = uieditfield(app.GridLayout11, 'numeric');
            app.MatrixField44.ValueDisplayFormat = '%8.4g';
            app.MatrixField44.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField44.Editable = 'off';
            app.MatrixField44.Value = 1;
            app.LoadedCoilPositionsListBox = uilistbox(app.GridLayout9);
            app.LoadedCoilPositionsListBox.Items = {};
            app.LoadedCoilPositionsListBox.ValueChangedFcn = createCallbackFcn(app, @LoadedCoilPositionsListBoxValueChanged, true);
            app.LoadedCoilPositionsListBox.Value = {};
            app.AnimateButton = uibutton(app.GridLayout9, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.GridLayout12 = uigridlayout(app.GridLayout9);
            app.VisualControlsLabel = uilabel(app.GridLayout12);
            app.VisualControlsLabel.Text = 'Visual Controls';
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.FieldVectorLabel = uilabel(app.GridLayout13);
            app.FieldVectorLabel.Text = 'Field Vector';
            app.FieldVectorSwitch = uiswitch(app.GridLayout13, 'slider');
            app.FieldVectorSwitch.ValueChangedFcn = createCallbackFcn(app, @VectortocoilSwitchValueChanged, true);
            app.FieldVectorSwitch.Tooltip = {'Toggle a vector from the brain to the coil'};
            app.FieldVectorSwitch.Value = 'On';
            app.VectorfromcoilSwitchLabel = uilabel(app.GridLayout13);
            app.VectorfromcoilSwitchLabel.Text = 'Vector from coil';
            app.VectorfromcoilSwitch = uiswitch(app.GridLayout13, 'slider');
            app.VectorfromcoilSwitch.ValueChangedFcn = createCallbackFcn(app, @VectortocoilSwitchValueChanged, true);
            app.VectorfromcoilSwitch.Tooltip = {'Toggle a vector showing the coil''s direction'};
            app.VectorfromcoilSwitch.Value = 'On';
            app.GridLayout14 = uigridlayout(app.GridLayout12);
            app.HeadCompartmentsDropDownLabel = uilabel(app.GridLayout14);
            app.HeadCompartmentsDropDownLabel.Text = 'Head Compartments';
            app.HeadCompartmentsDropDown = uidropdown(app.GridLayout14);
            app.HeadCompartmentsDropDown.Items = {};
            app.HeadCompartmentsDropDown.ValueChangedFcn = createCallbackFcn(app, @HeadCompartmentsDropDownValueChanged, true);
            app.HeadCompartmentsDropDown.Value = {};
            app.NiftiDataDropDownLabel = uilabel(app.GridLayout14);
            app.NiftiDataDropDownLabel.Text = 'Nifti Data';
            app.NiftiDataDropDown = uidropdown(app.GridLayout14);
            app.NiftiDataDropDown.Items = {};
            app.NiftiDataDropDown.ValueChangedFcn = createCallbackFcn(app, @NiftiDataDropDownValueChanged, true);
            app.NiftiDataDropDown.Value = {};
            app.GridLayout15 = uigridlayout(app.GridLayout12);
            app.ScalemmSliderLabel = uilabel(app.GridLayout15);
            app.ScalemmSliderLabel.Text = 'Scale (mm)';
            app.ScalemmSlider = uislider(app.GridLayout15);
            app.ScalemmSlider.Limits = [10 400];
            app.ScalemmSlider.MajorTicks = [10 100 200 300 400];
            app.ScalemmSlider.ValueChangedFcn = createCallbackFcn(app, @ScalemmSliderValueChanged, true);
            app.ScalemmSlider.MinorTicks = [50 150 250 350];
            app.ScalemmSlider.Tooltip = {'Change the axis size'};
            app.ScalemmSlider.Value = 120;
            app.StopButton = uibutton(app.GridLayout9, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.AddCoilPositionButton = uibutton(app.GridLayout9, 'push');
            app.AddCoilPositionButton.ButtonPushedFcn = createCallbackFcn(app, @AddCoilPositionButtonPushed, true);
            app.AddCoilPositionButton.Text = 'Add Coil Position';
            app.DeleteCoilPositionButton = uibutton(app.GridLayout9, 'push');
            app.DeleteCoilPositionButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteCoilPositionButtonPushed, true);
            app.DeleteCoilPositionButton.Text = 'Delete Coil Position';
            app.LoadedCoilPositionsListBoxLabel = uilabel(app.GridLayout9);
            app.LoadedCoilPositionsListBoxLabel.Text = 'Loaded Coil Positions';
            app.ProcessingTab = uitab(app.WindowTabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.GridLayout7 = uigridlayout(app.ProcessingTab);
            app.StartProcessingButton = uibutton(app.GridLayout7, 'push');
            app.StartProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessingButtonPushed, true);
            app.StartProcessingButton.Tooltip = {'Press once to start processing (this will take a few minutes)'};
            app.StartProcessingButton.Text = 'Start Processing';
            app.GeneralOptionsLabel = uilabel(app.GridLayout7);
            app.GeneralOptionsLabel.Text = 'General Options';
            app.LampStartProcessing = uilamp(app.GridLayout7);
            app.LampStartProcessing.Tooltip = {'Processing Indicator'};
            app.AdvancedViewCheckBox = uicheckbox(app.GridLayout7);
            app.AdvancedViewCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedViewCheckBoxValueChanged, true);
            app.AdvancedViewCheckBox.Tooltip = {'Changes visibility for advanced options panel'};
            app.AdvancedViewCheckBox.Text = 'Advanced View';
            app.AdvancedOptionsPanel = uipanel(app.GridLayout7);
            app.AdvancedOptionsPanel.Tooltip = {'These values can optionally be changed'};
            app.AdvancedOptionsPanel.Title = 'Advanced Options';
            app.AdvancedOptionsPanel.Visible = 'off';
            app.GridLayout52 = uigridlayout(app.AdvancedOptionsPanel);
            app.MinimumResidualEditFieldLabel = uilabel(app.GridLayout52);
            app.MinimumResidualEditFieldLabel.Text = 'Minimum Residual';
            app.MinimumResidualEditField = uieditfield(app.GridLayout52, 'numeric');
            app.MinimumResidualEditField.Value = 1e-12;
            app.CoilCurrentEditFieldLabel = uilabel(app.GridLayout52);
            app.CoilCurrentEditFieldLabel.Text = 'Coil Current';
            app.CoilCurrentEditField = uieditfield(app.GridLayout52, 'numeric');
            app.CoilCurrentEditField.Limits = [0 Inf];
            app.CoilCurrentEditField.ValueDisplayFormat = '%11.4gA';
            app.CoilCurrentEditField.Tooltip = {'Enter coil current in amperes'};
            app.CoilCurrentEditField.Value = 5000;
            app.CoildIdtEditFieldLabel = uilabel(app.GridLayout52);
            app.CoildIdtEditFieldLabel.Text = 'Coil dIdt';
            app.CoildIdtEditField = uieditfield(app.GridLayout52, 'numeric');
            app.CoildIdtEditField.Limits = [0 Inf];
            app.CoildIdtEditField.Tooltip = {'Default: 9.4e7'};
            app.CoildIdtEditField.Value = 94000000;
            app.MaximumIterationsEditFieldLabel = uilabel(app.GridLayout7);
            app.MaximumIterationsEditFieldLabel.Text = 'Maximum Iterations';
            app.MaximumIterationsEditField = uieditfield(app.GridLayout7, 'numeric');
            app.MaximumIterationsEditField.Limits = [0 Inf];
            app.MaximumIterationsEditField.RoundFractionalValues = 'on';
            app.MaximumIterationsEditField.ValueDisplayFormat = '%11.0f Iterations';
            app.MaximumIterationsEditField.Tooltip = {'This is the maximum number of iterations that the FMM solver will run (higher number = more accurate, but not by a lot after a certain point)'};
            app.MaximumIterationsEditField.Value = 10;
            app.NumberofcoresforprocessingLabel_9 = uilabel(app.GridLayout7);
            app.NumberofcoresforprocessingLabel_9.Text = 'Cores for Processing';
            app.NumberOfThreadsForProcessingEditField = uieditfield(app.GridLayout7, 'numeric');
            app.NumberOfThreadsForProcessingEditField.Limits = [-1 Inf];
            app.NumberOfThreadsForProcessingEditField.RoundFractionalValues = 'on';
            app.NumberOfThreadsForProcessingEditField.ValueDisplayFormat = '%11.4g Cores';
            app.NumberOfThreadsForProcessingEditField.Tooltip = {'Cores that will be allocatated to the parallel pool. Do not exceed available CPU core count.'; 'Note: This setting is only for the parallel pool - other calculations will still use all available CPU cores'};
            app.NumberOfThreadsForProcessingEditField.Value = -1;
            app.TextArea_2 = uitextarea(app.GridLayout7);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Tooltip = {'Note on plot threshold values'};
            app.TextArea_2.Value = {'Set to -1 to use all possible cores'};
            app.PostProcessingVolumeTab = uitab(app.WindowTabGroup);
            app.PostProcessingVolumeTab.Tooltip = {''};
            app.PostProcessingVolumeTab.Title = 'Post Processing Volume';
            app.GridLayout4 = uigridlayout(app.PostProcessingVolumeTab);
            app.SolverDisplay = uiaxes(app.GridLayout4);
            title(app.SolverDisplay, 'E-field Magnitude (V/m)')
            xlabel(app.SolverDisplay, 'X (mm)')
            ylabel(app.SolverDisplay, 'Y (mm)')
            zlabel(app.SolverDisplay, 'Z (mm)')
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.RedrawPlotButton = uibutton(app.GridLayout5, 'push');
            app.RedrawPlotButton.ButtonPushedFcn = createCallbackFcn(app, @RedrawPlotButtonPushed, true);
            app.RedrawPlotButton.Tooltip = {'Press once to start processing (this will take a few minutes)'};
            app.RedrawPlotButton.Text = 'Redraw Plot';
            app.PlaneSelectionLabel = uilabel(app.GridLayout5);
            app.PlaneSelectionLabel.Text = 'Plane Selection';
            app.PlaneSelectionDropDown = uidropdown(app.GridLayout5);
            app.PlaneSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @PlaneSelectionDropDownValueChanged, true);
            app.NumberofcoresforprocessingLabel_13 = uilabel(app.GridLayout5);
            app.NumberofcoresforprocessingLabel_13.Text = 'Plot Threshold Low';
            app.EFieldThresholdLow = uieditfield(app.GridLayout5, 'numeric');
            app.EFieldThresholdLow.Limits = [-1 Inf];
            app.EFieldThresholdLow.RoundFractionalValues = 'on';
            app.EFieldThresholdLow.ValueDisplayFormat = '%11.4g V/m';
            app.EFieldThresholdLow.Tooltip = {'Plot color low threshold value. Set to -1 for auto'};
            app.EFieldThresholdLow.Value = 20;
            app.NumberofcoresforprocessingLabel_14 = uilabel(app.GridLayout5);
            app.NumberofcoresforprocessingLabel_14.Text = 'Plot Threshold High';
            app.EFieldThresholdHigh = uieditfield(app.GridLayout5, 'numeric');
            app.EFieldThresholdHigh.Limits = [-1 Inf];
            app.EFieldThresholdHigh.RoundFractionalValues = 'on';
            app.EFieldThresholdHigh.ValueDisplayFormat = '%11.4g V/m';
            app.EFieldThresholdHigh.Tooltip = {'Plot color high threshold value. Set to -1 for auto'};
            app.EFieldThresholdHigh.Value = 160;
            app.TextArea_3 = uitextarea(app.GridLayout5);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Tooltip = {'Note on plot threshold values'};
            app.TextArea_3.Value = {'Set either threshold to -1 to use an automatic setting'};
            app.NumberofcoresforprocessingLabel_15 = uilabel(app.GridLayout5);
            app.NumberofcoresforprocessingLabel_15.Text = 'Plot Levels';
            app.EFieldPlotLevels = uieditfield(app.GridLayout5, 'numeric');
            app.EFieldPlotLevels.Limits = [1 Inf];
            app.EFieldPlotLevels.RoundFractionalValues = 'on';
            app.EFieldPlotLevels.ValueDisplayFormat = '%11.4g Levels';
            app.EFieldPlotLevels.Tooltip = {'Number of levels (or steps) that will be processed and displayed for the plot. '};
            app.EFieldPlotLevels.Value = 30;
            app.CoilSelectionLabel = uilabel(app.GridLayout5);
            app.CoilSelectionLabel.Text = 'Coil Selection';
            app.VolumeCoilSelectionDropDown = uidropdown(app.GridLayout5);
            app.VolumeCoilSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @PlaneSelectionDropDownValueChanged, true);
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.CrossSectionPPDisplay = uiaxes(app.GridLayout6);
            title(app.CrossSectionPPDisplay, 'Cross Section View')
            xlabel(app.CrossSectionPPDisplay, 'X (mm)')
            ylabel(app.CrossSectionPPDisplay, 'Y (mm)')
            zlabel(app.CrossSectionPPDisplay, 'Z (mm)')
            app.CrossSectionPPDisplay.XLim = [-100 100];
            app.CrossSectionPPDisplay.YLim = [-100 100];
            app.CrossSectionPPDisplay.ZLim = [-100 100];
            app.CrossSectionPPDisplay.XTick = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.CoilPPDisplay = uiaxes(app.GridLayout6);
            title(app.CoilPPDisplay, '3D View')
            xlabel(app.CoilPPDisplay, 'X (mm)')
            ylabel(app.CoilPPDisplay, 'Y (mm)')
            zlabel(app.CoilPPDisplay, 'Z (mm)')
            app.PostProcessingSurfaceTab = uitab(app.WindowTabGroup);
            app.PostProcessingSurfaceTab.Title = 'Post Processing Surface';
            app.GridLayout50 = uigridlayout(app.PostProcessingSurfaceTab);
            app.SurfaceDisplay = uiaxes(app.GridLayout50);
            title(app.SurfaceDisplay, 'E-field Magnitude (V/m)')
            xlabel(app.SurfaceDisplay, 'X (mm)')
            ylabel(app.SurfaceDisplay, 'Y (mm)')
            zlabel(app.SurfaceDisplay, 'Z (mm)')
            app.ScaleSurfaceSlider = uislider(app.GridLayout50);
            app.ScaleSurfaceSlider.Limits = [10 400];
            app.ScaleSurfaceSlider.ValueChangedFcn = createCallbackFcn(app, @ScaleSurfaceSliderValueChanged, true);
            app.ScaleSurfaceSlider.Value = 120;
            app.LampSurfacePlot = uilamp(app.GridLayout50);
            app.LampSurfacePlot.Tooltip = {'Processing Indicator'};
            app.SurfaceHeadCompartmentsDropDown = uidropdown(app.GridLayout50);
            app.SurfaceHeadCompartmentsDropDown.Items = {};
            app.SurfaceHeadCompartmentsDropDown.ValueChangedFcn = createCallbackFcn(app, @SurfaceHeadCompartmentsDropDownValueChanged, true);
            app.SurfaceHeadCompartmentsDropDown.Value = {};
            app.ScalemmSlider_2Label = uilabel(app.GridLayout50);
            app.ScalemmSlider_2Label.Text = 'Scale (mm)';
            app.HeadCompartmentsDropDown_2Label = uilabel(app.GridLayout50);
            app.HeadCompartmentsDropDown_2Label.Text = 'Head Compartments';
            app.SurfaceEFieldLocationSwitchLabel = uilabel(app.GridLayout50);
            app.SurfaceEFieldLocationSwitchLabel.Text = 'Surface EField Location';
            app.SurfaceEFieldLocationSwitch = uiswitch(app.GridLayout50, 'slider');
            app.SurfaceEFieldLocationSwitch.Items = {'Inside Layer', 'Outside Layer'};
            app.SurfaceEFieldLocationSwitch.ValueChangedFcn = createCallbackFcn(app, @SurfaceHeadCompartmentsDropDownValueChanged, true);
            app.SurfaceEFieldLocationSwitch.Value = 'Inside Layer';
            app.FieldTypeDropDownLabel = uilabel(app.GridLayout50);
            app.FieldTypeDropDownLabel.Text = 'Field Type';
            app.FieldSourceTypeDropDown = uidropdown(app.GridLayout50);
            app.FieldSourceTypeDropDown.Items = {'Total Field', 'Primary Field', 'Secondary Field', ''};
            app.FieldSourceTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SurfaceHeadCompartmentsDropDownValueChanged, true);
            app.FieldSourceTypeDropDown.Value = 'Total Field';
            app.FieldValueTypeDropDown = uidropdown(app.GridLayout50);
            app.FieldValueTypeDropDown.Items = {'Total Magnitude', 'Normal Component', 'Tangential Component'};
            app.FieldValueTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SurfaceHeadCompartmentsDropDownValueChanged, true);
            app.FieldValueTypeDropDown.Value = 'Total Magnitude';
            app.TextArea_4 = uitextarea(app.GridLayout50);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Tooltip = {'Note on plot threshold values'};
            app.TextArea_4.Value = {'Set either threshold to -1 to use an automatic setting'};
            app.NumberofcoresforprocessingLabel_16 = uilabel(app.GridLayout50);
            app.NumberofcoresforprocessingLabel_16.Text = 'Plot Threshold Low';
            app.SurfaceThresholdLow = uieditfield(app.GridLayout50, 'numeric');
            app.SurfaceThresholdLow.Limits = [-1 Inf];
            app.SurfaceThresholdLow.RoundFractionalValues = 'on';
            app.SurfaceThresholdLow.ValueDisplayFormat = '%11.4g V/m';
            app.SurfaceThresholdLow.Tooltip = {'Plot color low threshold value. Set to -1 for auto'};
            app.SurfaceThresholdLow.Value = -1;
            app.NumberofcoresforprocessingLabel_17 = uilabel(app.GridLayout50);
            app.NumberofcoresforprocessingLabel_17.Text = 'Plot Threshold High';
            app.SurfaceThresholdHigh = uieditfield(app.GridLayout50, 'numeric');
            app.SurfaceThresholdHigh.Limits = [-1 Inf];
            app.SurfaceThresholdHigh.RoundFractionalValues = 'on';
            app.SurfaceThresholdHigh.ValueDisplayFormat = '%11.4g V/m';
            app.SurfaceThresholdHigh.Tooltip = {'Plot color high threshold value. Set to -1 for auto'};
            app.SurfaceThresholdHigh.Value = -1;
            app.RedrawSurfacePlotButton = uibutton(app.GridLayout50, 'push');
            app.RedrawSurfacePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SurfaceHeadCompartmentsDropDownValueChanged, true);
            app.RedrawSurfacePlotButton.Tooltip = {'Press once to start processing (this will take a few minutes)'};
            app.RedrawSurfacePlotButton.Text = 'Redraw Plot';
            app.CoilSelectionLabel_2 = uilabel(app.GridLayout50);
            app.CoilSelectionLabel_2.Text = 'Coil Selection';
            app.SurfaceCoilSelectionDropDown = uidropdown(app.GridLayout50);
            app.SurfaceCoilSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @SurfaceHeadCompartmentsDropDownValueChanged, true);
            app.ExportingTab = uitab(app.WindowTabGroup);
            app.ExportingTab.Title = 'Exporting';
            app.DataExportTree = uitree(app.ExportingTab, 'checkbox');
            app.HeadModelNode = uitreenode(app.DataExportTree);
            app.HeadModelNode.Text = 'Head Model';
            app.CoilNode = uitreenode(app.DataExportTree);
            app.CoilNode.Text = 'Coil';
            app.EFieldNode = uitreenode(app.DataExportTree);
            app.EFieldNode.Text = 'EField';
            app.SurfacesNode = uitreenode(app.DataExportTree);
            app.SurfacesNode.Text = 'Surfaces';
            app.SurfacesPlottingExampleNode = uitreenode(app.DataExportTree);
            app.SurfacesPlottingExampleNode.Text = 'Surfaces Plotting Example';
            app.PlanesNode = uitreenode(app.DataExportTree);
            app.PlanesNode.Text = 'Planes';
            app.Label = uilabel(app.ExportingTab);
            app.LampExporting = uilamp(app.ExportingTab);
            app.LampExporting.Tooltip = {'Processing Indicator'};
            app.ExportButton = uibutton(app.ExportingTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end